classdef TEMPLATE_ck_rc3_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ClusterDefinitionTab            matlab.ui.container.Tab
        exemplarydatafileLabel          matlab.ui.control.Label
        exemplarydatafileEditField      matlab.ui.control.EditField
        exemplarydatadirButton          matlab.ui.control.Button
        DefineClusterButton             matlab.ui.control.Button
        DefineNetworkButton             matlab.ui.control.Button
        deletenetworkdefinitionsfromClusterstructButton  matlab.ui.control.Button
        DataimportTab                   matlab.ui.container.Tab
        g1dirdataimportButton           matlab.ui.control.Button
        g2dirdataimportButton           matlab.ui.control.Button
        g1dirdataimportListBox          matlab.ui.control.ListBox
        g2dirdataimportListBox          matlab.ui.control.ListBox
        rootdirgroup1EditField_2Label   matlab.ui.control.Label
        g1dirdataimportEditField        matlab.ui.control.EditField
        rootdirgroup2EditField_2Label   matlab.ui.control.Label
        g2dirdataimportEditField        matlab.ui.control.EditField
        subdirnameEditField_2Label      matlab.ui.control.Label
        dataimportsubdirnameEditField   matlab.ui.control.EditField
        OptionsforvoxelextractionLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        extractallvoxelinClusterCheckBox  matlab.ui.control.CheckBox
        savedasClussteriYLabel          matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        extractconditionalvoxelCheckBox  matlab.ui.control.CheckBox
        savedasClussteriYc1Label        matlab.ui.control.Label
        graymatterprobgreaterasEditFieldLabel  matlab.ui.control.Label
        graymatterprobgreaterasEditField  matlab.ui.control.NumericEditField
        whitematterproblessthenEditFieldLabel  matlab.ui.control.Label
        whitematterproblessthenEditField  matlab.ui.control.NumericEditField
        CSFproblessthenEditFieldLabel   matlab.ui.control.Label
        CSFproblessthenEditField        matlab.ui.control.NumericEditField
        graymatterfilenameEditFieldLabel  matlab.ui.control.Label
        graymatterfilenameEditField     matlab.ui.control.EditField
        whitematterfilenameEditFieldLabel  matlab.ui.control.Label
        whitematterfilenameEditField    matlab.ui.control.EditField
        csffilenameEditFieldLabel       matlab.ui.control.Label
        csffilenameEditField            matlab.ui.control.EditField
        Lamp                            matlab.ui.control.Lamp
        testforfilepresenceButton       matlab.ui.control.Button
        extract2conditionalvoxelCheckBox  matlab.ui.control.CheckBox
        graymatterprobgreaterasEditField_2Label  matlab.ui.control.Label
        graymatterprobgreateras2EditField  matlab.ui.control.NumericEditField
        whitematterproblessthenEditField_2Label  matlab.ui.control.Label
        whitematterproblessthen2EditField  matlab.ui.control.NumericEditField
        CSFproblessthenEditField_2Label  matlab.ui.control.Label
        CSFproblessthen2EditField       matlab.ui.control.NumericEditField
        savedasClussteriYc2Label        matlab.ui.control.Label
        GOButton                        matlab.ui.control.Button
        updatedataimportButton          matlab.ui.control.Button
        filefilterLabel                 matlab.ui.control.Label
        dataimportfilefilterEditField   matlab.ui.control.EditField
        DatapropertiesPanel             matlab.ui.container.Panel
        TRinsecEditFieldLabel           matlab.ui.control.Label
        TREditField                     matlab.ui.control.NumericEditField
        lpfEditFieldLabel               matlab.ui.control.Label
        lpfEditField                    matlab.ui.control.NumericEditField
        hpfEditFieldLabel               matlab.ui.control.Label
        hpfEditField                    matlab.ui.control.NumericEditField
        connectivityestimationTab       matlab.ui.container.Tab
        estimateconnectivityButton      matlab.ui.control.Button
        estimationoptionsPanel          matlab.ui.container.Panel
        Cluster2VoxelCheckBox           matlab.ui.control.CheckBox
        Cluster2ClusterCheckBox         matlab.ui.control.CheckBox
        Voxel2VoxelCheckBox             matlab.ui.control.CheckBox
        C1maskthresholdCheckBox         matlab.ui.control.CheckBox
        thresholdEditFieldLabel         matlab.ui.control.Label
        thresholdEditField              matlab.ui.control.NumericEditField
        smoothingCheckBox               matlab.ui.control.CheckBox
        kernelEditFieldLabel            matlab.ui.control.Label
        kernelEditField                 matlab.ui.control.EditField
        MethodsPanel                    matlab.ui.container.Panel
        synchronizationPanel            matlab.ui.container.Panel
        readmesynchronizationButton     matlab.ui.control.Button
        histogramEditField_2Label       matlab.ui.control.Label
        histogramsynchronizationEditField  matlab.ui.control.NumericEditField
        complexityPanel                 matlab.ui.container.Panel
        windowlengthEditFieldLabel      matlab.ui.control.Label
        windowlengthcomplexityEditField  matlab.ui.control.NumericEditField
        similaritymeasureEditFieldLabel  matlab.ui.control.Label
        similaritymeasurecomplexityEditField  matlab.ui.control.NumericEditField
        readmecomplexityButton          matlab.ui.control.Button
        transinformationPanel           matlab.ui.container.Panel
        readmetransinformationButton    matlab.ui.control.Button
        histogramEditFieldLabel         matlab.ui.control.Label
        histogramEditField              matlab.ui.control.NumericEditField
        timelagEditFieldLabel           matlab.ui.control.Label
        timelagEditField                matlab.ui.control.NumericEditField
        complexitySwitch                matlab.ui.control.ToggleSwitch
        correlationsPanel               matlab.ui.container.Panel
        coherencePanel                  matlab.ui.container.Panel
        readmecoherenceButton           matlab.ui.control.Button
        partialcoherencePanel           matlab.ui.container.Panel
        readmepartialcoherenceButton    matlab.ui.control.Button
        synchronizationSwitch           matlab.ui.control.ToggleSwitch
        transinformationSwitch          matlab.ui.control.ToggleSwitch
        partialcoherenceSwitch          matlab.ui.control.ToggleSwitch
        coherenceSwitch                 matlab.ui.control.ToggleSwitch
        correlationSwitch               matlab.ui.control.ToggleSwitch
        grangerSwitch                   matlab.ui.control.ToggleSwitch
        GrangercausalitybigPanel        matlab.ui.container.Panel
        GrangercausalityPanel           matlab.ui.container.Panel
        conditionalGCinthetimedomainCheckBox  matlab.ui.control.CheckBox
        additionalpreprocessingPanel    matlab.ui.container.Panel
        grangerdetrenddataCheckBox      matlab.ui.control.CheckBox
        grangerremovetemporalmeanCheckBox  matlab.ui.control.CheckBox
        grangerremovetemporalmeananddividebystdCheckBox  matlab.ui.control.CheckBox
        grangerdifferencingtimeseriesCheckBox  matlab.ui.control.CheckBox
        iterationEditFieldLabel         matlab.ui.control.Label
        grangeriterationEditField       matlab.ui.control.NumericEditField
        readmegrangerpreprocessingButton  matlab.ui.control.Button
        estimategrangerautonomyCheckBox  matlab.ui.control.CheckBox
        conditionalGCinfrequencydomainCheckBox  matlab.ui.control.CheckBox
        readmegrangerpreprocessingButton_2  matlab.ui.control.Button
        CGCtimeparameterPanel           matlab.ui.container.Panel
        numberoflagsEditFieldLabel      matlab.ui.control.Label
        grangernumberoflagsEditField    matlab.ui.control.NumericEditField
        pEditFieldLabel                 matlab.ui.control.Label
        grangerpEditField               matlab.ui.control.NumericEditField
        frequencyparameterPanel         matlab.ui.container.Panel
        frequencyiterationstepEditFieldLabel  matlab.ui.control.Label
        grangerfrequencyiterationstepEditField  matlab.ui.control.NumericEditField
        frequencyEditFieldLabel         matlab.ui.control.Label
        grangerfrequencyEditField       matlab.ui.control.EditField
        selectedClusterListBoxLabel     matlab.ui.control.Label
        grangerselectedClusterListBox   matlab.ui.control.ListBox
        usedataPanel                    matlab.ui.container.Panel
        conuseYCheckBox                 matlab.ui.control.CheckBox
        conuseYc1CheckBox               matlab.ui.control.CheckBox
        conuseYc2CheckBox               matlab.ui.control.CheckBox
        datareductionTab                matlab.ui.container.Tab
        deleteifpossiblePanel           matlab.ui.container.Panel
        YCheckBox                       matlab.ui.control.CheckBox
        Yc1CheckBox                     matlab.ui.control.CheckBox
        Yc2CheckBox                     matlab.ui.control.CheckBox
        newprefixforClustermatfilesEditFieldLabel  matlab.ui.control.Label
        newprefixforClustermatfilesEditField  matlab.ui.control.EditField
        reduceClusterdataButton         matlab.ui.control.Button
        ExplanationTextAreaLabel        matlab.ui.control.Label
        ExplanationTextArea             matlab.ui.control.TextArea
        visualizationTab                matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Cluster2Cluster                 matlab.ui.container.Tab
        loadeddataPanel                 matlab.ui.container.Panel
        C2CloaddataButton               matlab.ui.control.Button
        C2CloaddataLamp                 matlab.ui.control.Lamp
        group1ListBoxLabel              matlab.ui.control.Label
        C2Cgroup1ListBox                matlab.ui.control.ListBox
        group2ListBoxLabel              matlab.ui.control.Label
        C2Cgroup2ListBox                matlab.ui.control.ListBox
        C2CnormalizeCheckBox            matlab.ui.control.CheckBox
        C2CestimateClusterstatisticButton  matlab.ui.control.Button
        C2CstatisticButtonGroup         matlab.ui.container.ButtonGroup
        C2CtwosampleButton              matlab.ui.control.ToggleButton
        C2CpairedButton                 matlab.ui.control.ToggleButton
        C2CestimatedataLamp             matlab.ui.control.Lamp
        C2CDatatouseButtonGroup         matlab.ui.container.ButtonGroup
        C2CYButton                      matlab.ui.control.RadioButton
        C2CYc1Button                    matlab.ui.control.RadioButton
        C2CYc2Button                    matlab.ui.control.RadioButton
        C2CestimateClusterstatisticButton_2  matlab.ui.control.Button
        C2CloaddataButton_2             matlab.ui.control.Button
        C2CshownetworksButton           matlab.ui.control.Button
        BrainconnectivityPanel          matlab.ui.container.Panel
        C2CmeanZdiffButton              matlab.ui.control.Button
        C2CBCsigdiffButton              matlab.ui.control.Button
        BehaviorvsBrainconnectivitywithEXELdataPanel  matlab.ui.container.Panel
        xlscsvFileEditFieldLabel        matlab.ui.control.Label
        xlscsvFileEditField             matlab.ui.control.EditField
        C2CchooseexelfileButton         matlab.ui.control.Button
        C2CexelreadmeButton             matlab.ui.control.Button
        loadexelfileButton              matlab.ui.control.Button
        ExelvariablesListBoxLabel       matlab.ui.control.Label
        ExelvariablesListBox            matlab.ui.control.ListBox
        C2CestimatecorrelationButton    matlab.ui.control.Button
        visualizecorrelationButton      matlab.ui.control.Button
        C2CestimateexelcorrelationLamp  matlab.ui.control.Lamp
        C2CvisualizenetworkcorrelationButton  matlab.ui.control.Button
        C2CestimatenetworkcorrelationButton  matlab.ui.control.Button
        Cluster2Voxel                   matlab.ui.container.Tab
        Voxel2Voxel                     matlab.ui.container.Tab
        Cluster2Cluster_Granger         matlab.ui.container.Tab
        GlobalParameterPanel            matlab.ui.container.Panel
        resultsrootdirEditFieldLabel    matlab.ui.control.Label
        resultsdirEditField             matlab.ui.control.EditField
        Buttonchooseresultsdir          matlab.ui.control.Button
        Buttonchooseg1dir               matlab.ui.control.Button
        group1dirEditFieldLabel         matlab.ui.control.Label
        g1dirEditField                  matlab.ui.control.EditField
        chooseg2dir                     matlab.ui.control.Button
        group2dirEditFieldLabel         matlab.ui.control.Label
        g2dirEditField                  matlab.ui.control.EditField
        loadlastButton                  matlab.ui.control.Button
        loadButton                      matlab.ui.control.Button
        saveButton                      matlab.ui.control.Button
        MultiprocessoroptionsCheckBox   matlab.ui.control.CheckBox
        initnewButton                   matlab.ui.control.Button
        idleGaugeLabel                  matlab.ui.control.Label
        idleGauge                       matlab.ui.control.LinearGauge
        loadnewsbButton                 matlab.ui.control.Button
        savenewsbButton                 matlab.ui.control.Button
        g1ListBox                       matlab.ui.control.ListBox
        g2ListBox                       matlab.ui.control.ListBox
        group1directoryLabel            matlab.ui.control.Label
        group2directoryLabel            matlab.ui.control.Label
        updateButton                    matlab.ui.control.Button
    end


    properties (Access = private)

    end
    
    properties (Access = public)
        IDlist % Liste mit den IDs der Subjects
        exeltitel % Description
        exeldata
        C2C_stat_g1 % Description
        C2C_stat_g1_org
        C2C_stat_g2 % Description
        C2C_stat_g2_org
        C2C_stat_g1_des
        C2C_stat_g2_des
        Property8 % Description
        C2C_stat % Description
        namelist1
        namelist2% Description
    end

    methods (Access = private)
        
        function stat = listboxupdate(app)
            stat = 1;
            fprintf('updating listbox\n');


            g1 = dir([app.g1dirEditField.Value filesep '*.mat']);
            g2 = dir([app.g2dirEditField.Value filesep '*.mat']);
            
            listg1{1}='empty';
            idxg1 = 1;
            listg2{1}='empty';
            idxg2 = 1;
            
            Valg1(1)=1;
            Valg2(1)=1;
            for i=1:size(g1,1)
                listg1{i} = g1(i,1).name;
            end
            for i=1:size(g2,1)
                listg2{i} = g2(i,1).name;
            end
            app.g1ListBox.Items=listg1;
            app.g2ListBox.Items=listg2;
            
            % if idxg1>1
            %     set(handles.listboxg1,'Value',Valg1);
            % else
            %     set(handles.listboxg1,'Value',1);
            % end
            % if idxg2>1
            %     set(handles.listboxg2,'Value',Valg2);
            % else
            %     set(handles.listboxg2,'Value',1);
            % end
            %
            %save(fullfile(fileparts(which('ck_rc2.m')),'lastprocessingdir.mat'),'lastprocessingdir');
            % speichere REchnerspezifisch unter Hilfe der MAC Adresse des REchners
            
            %[~,result] = dos('getmac')
            %mac = result(160:176);
            mac = '123456789101010';
            try
                load(fileparts(which(which('ck_rc3.mlapp')),['lastsession_' mac '.mat']),'lastsession');
                
                cd(app.resultsdirEditField.Value);
            catch
                lastsession.resultsdir = app.resultsdirEditField.Value;
                cd(app.resultsdirEditField.Value);
            end
            lastsession.resultsdir = app.resultsdirEditField.Value;
            lastsession.datadirg1 = app.g1dirEditField.Value;
            lastsession.datadirg2 = app.g2dirEditField.Value;
            %     lastsession.multifileg1 = handles.multifileg1;
            %     lastsession.multifileg2 = handles.multifileg2;
            savefile = fullfile(fileparts(which('ck_rc3.mlapp')),['lastsession_' mac '.mat']);
            save(savefile,'lastsession');
            parameterfile = fullfile(app.resultsdirEditField.Value,'parameter.mat');
            try
                load(parameterfile);
            catch
                parameter = struct;
            end
            parameter.outdir = app.resultsdirEditField.Value;
            parameter.datadirg1 = app.g1dirEditField.Value;
            parameter.datadirg2 = app.g2dirEditField.Value;
            try
                save(parameterfile,'parameter');
            catch
                fprintf('fehler beim Speichern des Parameterfiles\n');
            end
            
        end
        
        function C = del_network(app,C)
            for i=1:length(C)
                if isfield(C{i},'net')
                    C{i} = rmfield(C{i},'net');
                end
                if isfield(C{i},'group')
                    C{i} = rmfield(C{i},'group');
                end
                if isfield(C{i},'groupname')
                    C{i} = rmfield(C{i},'groupname');
                end
                if isfield(C{i},'groupm')
                    C{i} = rmfield(C{i},'groupm');
                end
                if isfield(C{i},'groupnamem')
                    C{i} = rmfield(C{i},'groupnamem');
                end
                if isfield(C{i},'num_global_networks')
                    C{i} = rmfield(C{i},'num_global_networks');
                end
                if isfield(C{i},'num_local_networks')
                    C{i} = rmfield(C{i},'num_local_networks');
                end
            end
            
        end
        
        function cell_list = get_updatedataimport_list(app,rootdir,filter)
            %rootdir = 'H:\data_ck\Vesti\data';
            %filter = 'filter_rs6';
            cell_list = {};
            dx = dir(rootdir);
            idx = 0;
            %dx2 = dx;
            for i=1:length(dx)
                if dx(i).isdir && ~strcmp(dx(i).name,'.') && ~strcmp(dx(i).name,'..')
                    idx = idx + 1;
                    dx2(idx)=dx(i);
                end
            end
            assignin('base','dx2',dx2);
            
            idx = 0;
            for i=1:length(dx2)
                dtmp = dir([rootdir, filesep ,dx2(i).name]);
                subdir_exist = 0;
                for j=1:length(dtmp)
                    
                    if dtmp(j).isdir && ~strcmp(dtmp(j).name,'.') && ~strcmp(dtmp(j).name,'..')
                        
                        if strcmp(dtmp(j).name,filter)
                            %fprintf('korrektes unterverzeichnis gefunden %.0d %.0d\n',i,j)
                            subdir_exist = 1;
                        end
                    end
                end
                if subdir_exist || isempty(filter)
                    idx = idx + 1;
                    rel_subdirs(idx)=dx2(i);
                    cell_list{idx} = dx2(i).name;
                end
                % wenn es keinen Filter gibt dann sollten die Bilder im Subjectverzeichnis sein
                
            end
            assignin('base','cell_list',cell_list);
            
        end
        
        function file_list = get_full_subjects_filenames(app,path,Items)
            %assignin('base','Items',Items);
%            length(Items)
            subdir = app.dataimportsubdirnameEditField.Value;
            filefilter = app.dataimportfilefilterEditField.Value;
            for i=1:length(Items)
                
                tmp = [path filesep Items{i} filesep filefilter];
                if ~isempty(subdir)
                    tmp = [path filesep Items{i} filesep subdir filesep filefilter];
                end
%                tmp = [p filefilter];
                
                files = dir(tmp);
                assignin('base','files',files);
                
                for j=1:length(files)
                    name = fullfile(files(j).folder,files(j).name);
                    file_list{i}(j,1:length(name))=name;
                end
                %file_list{i} = spm_vol(name);
%                path_list{i}=[path filesep Items{i} filesep subdir];
            end
                            
 
            %assignin('base','file_list',file_list);
        end


        function error = test_existence(app,rootdir,pathlist)
            error = 0;
            for i=1:length(pathlist)
                
                p = [rootdir filesep pathlist{i} filesep app.dataimportsubdirnameEditField.Value];
%                pf = Vm{i}(1,1).fname; 
%                [p,f] = fileparts(pf);

                e1 = exist(fullfile(p,app.graymatterfilenameEditField.Value));
                e2 = exist(fullfile(p,app.graymatterfilenameEditField.Value));
                e3 = exist(fullfile(p,app.graymatterfilenameEditField.Value));
               
                if (e1+e2+e3)~=6
                    fprintf('no c1-2-3 files were found in folder %s \n',p);
                    error = 1;
                end
            end
        end
        
        function [listg1,listg2] = get_all_selected_filenames(app,string)
            
            d1 = [app.g1dirEditField.Value,filesep'];
            d2 = [app.g2dirEditField.Value,filesep'];
            
            listg1 = app.g1ListBox.Value;
            listg2 = app.g2ListBox.Value;
            
            if strcmp(string,'full')
                listg1 = strcat(d1,listg1);
                listg2 = strcat(d2,listg2);
            end

        end
        
        function Cluster = reduce_Cluster(app,filelist,prefix)
            % entfernt die Y Daten und speichert die Cluster unter neuem Namen ab
            
            for i=1:length(filelist)
                fprintf('reduce_Cluster %.0d / %.0d subjects\n',i,length(filelist));
                file = filelist{i};
                [p,f,e] = fileparts(file);
                newfilename = fullfile(p,[prefix, f, e]);
                load(file); % load Cluster
                for j=1:length(Cluster)
                    
                    if isfield(Cluster{j},'Y') && app.YCheckBox.Value
                        Cluster{j} = rmfield(Cluster{j},'Y');
                    end
                    if isfield(Cluster{j},'Yc1') && app.Yc1CheckBox.Value
                        Cluster{j} = rmfield(Cluster{j},'Yc1');
                    end
                    if isfield(Cluster{j},'Yc2') && app.Yc2CheckBox.Value
                        Cluster{j} = rmfield(Cluster{j},'Yc2');
                    end
                end
                save(newfilename,'Cluster');
                
            end
            
        end
        
        function C2C_stat_g1 = deslect_subjects_ck(app,C2C_stat_g1,namelist,idx)
            %fprintf('deselect subjects\n');
            if length(idx)==0
                return;
            end
            %C2C_stat_g1.Z
            
            
            if isfield(C2C_stat_g1,'R'); C2C_stat_g1.R(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'P'); C2C_stat_g1.P(:,:,idx)=[];  end
            if isfield(C2C_stat_g1,'Z'); C2C_stat_g1.Z(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'Syn'); C2C_stat_g1.Syn(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'PLV'); C2C_stat_g1.PLV(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'T'); C2C_stat_g1.T(:,:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'Coh'); C2C_stat_g1.Coh(:,:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'CohF'); C2C_stat_g1.CohF(:,:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'Power'); C2C_stat_g1.Power(:,idx)=[]; end
            if isfield(C2C_stat_g1,'Comp'); C2C_stat_g1.Comp(:,idx)=[]; end
            if isfield(C2C_stat_g1,'RiN'); C2C_stat_g1.RiN(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'RN'); C2C_stat_g1.RN(:,idx)=[]; end
            if isfield(C2C_stat_g1,'ZiN'); C2C_stat_g1.ZiN(:,:,idx)=[]; end
            if isfield(C2C_stat_g1,'ZN'); C2C_stat_g1.ZN(:,idx)=[]; end
            if isfield(C2C_stat_g1,'namelist'); C2C_stat_g1.namelist = namelist; end


        end
        
        function unsel = get_unselected_idx(app,listbox)
            tmpall = listbox.Items;
            tmpsel = listbox.Value;
            tmp = ismember(tmpall,tmpsel)==0;
            unsel = find(tmp);
        end
        
        function C2C = make_C2C_group_struct(app,dir,namelist,postfix)
            % postfix definiert die Daten die rausgeschrieben werden Y Yc1, Yc2
            h=waitbar(0,'Please wait');
            
            % gibt es eine Netzwerkdefinition ?
            load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
            if isfield(Cluster,'net')
                net =1;
            end
            
            load(fullfile(dir,namelist{1,1}));
            C2C.R=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            C2C.P=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            C2C.Z=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            C2C.Syn=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            %C2C.T=zeros(size(Cluster,1),1,size(Cluster,1),length(namelist));
            C2C.PLV=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            Power=zeros(size(Cluster,1),length(namelist));
            clusterXYZ = cell(0);
            for i=1:length(namelist)
                waitbar(i / length(namelist));
                fprintf('load Cluster %s \n',fullfile(dir,namelist{i}));
                load(fullfile(dir,namelist{i}));
                % die Clusternamen und Koordinaten werden aus einem
                % Cluster extrahiert (alle Cluster müssen natürlich gleich sein)
                %fprintf('%d\n',size(Cluster,1));
                %fprintf('loading %s\n',namelist{i});
                for j=1:size(Cluster,1)
                    clusternames{j,1}=Cluster{j,1}.name;
                    if isfield(Cluster{j,1},'XYZ')
                        clusterXYZ{j,1}=Cluster{j,1}.XYZ;
                    end
                    %assignin('base','Cluster',Cluster);
                    if ~isfield(Cluster{j,1},'Cluster2Cluster')
                        error('please estimate C2C connectivity first (no Cluster2Cluster field in Cluster struct)');
                        
                    end
                   
                    if isfield(Cluster{j,1}.Cluster2Cluster,['R', postfix])
                        C2C.R(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['R', postfix]);
                        C2C.P(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['P', postfix]);
                        C2C.Z(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['Z', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['T', postfix]))
                        Tx = Cluster{j,1}.Cluster2Cluster.(['T', postfix]);
                        C2C.T(:,1:size(Tx,1),j,i)=Tx';
                    end
                    % hier manchmal komplexe Zahlen ... evtl bei merkwuerdiegen Y_mean eingabe
                    try
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Coh', postfix]))
                        Coh = Cluster{j,1}.Cluster2Cluster.(['Coh', postfix]);
                        CohF = Cluster{j,1}.Cluster2Cluster.(['CohF', postfix]);
                        C2C.Coh(:,1:size(Coh,2),j,i)=Coh;
                        C2C.CohF(:,1:size(Coh,2),j,i)=CohF;
                    end
                    catch
                        fprintf('choerence konnte nicht berechnet werden warscheinlich Y_mean ungeeignet\n');
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Syn', postfix]))
                        C2C.Syn(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['Syn', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['PLV', postfix]))
                        C2C.PLV(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['PLV', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Comp', postfix]))
                        C2C.Comp(j,i)=Cluster{j,1}.Cluster2Cluster.(['Comp', postfix]);
                    end
                    Y_mean = Cluster{j,1}.(['Y', postfix, '_mean']);
                    C2C.Power(j,i)=sum(Y_mean.^2)/(length(Y_mean)*2+1);
                    
                    % Netzwerk einfuegen 
                    % C2C.RiN
                    % C2C.ZiN
                    
                end
                
            end
            close(h)
            C2C.clusternames=clusternames;
            C2C.clusterXYZ=clusterXYZ;
            C2C.namelist = namelist;
        end
        
        function idx = get_rows_of_subjects(app,IDlist,names)
            assignin('base','IDlist',IDlist)
            k = 1;
            for i=1:length(names)
                
                assignin('base','names',names);
                id = str2num(names{i}(end-7:end-4));
                tmp = find(IDlist==id);
                if isempty(tmp)
                    sprintf(str,'Patient ID not found %s ',id);
                    msgdlg(str);
                else
                idx(k) = tmp;
                k = k + 1;
                end
            end
        end
        
        
        % berechnet die Korrelation zwischen Konnektivitaet und behavioralen Daten
        %function [R_3d,R,P,clusternames] = get_Rg(d,group,B,handles)
        function [G] = get_Rg(app, d, group, B)
            % berechnet die Korrelation zwischen Konnektivitaet und behavioralen Daten
            %function [R_3d,R,P,clusternames] = get_Rg(d,group,B,handles)
            % gibt die Variable G zurueck mit den Eintraegen
            % G.R_3d
            % G.R
            % G.P
            % G.clusternames
            % falls vorhanden noch folgende weitere
            % G.Syn
            % G.PLV
            % G.
            assignin('base','B',B);
            file = fullfile(d,'C2C_stat.mat');
            try
                load(file)
            catch
                msgbox('please estimate Cluster statistic first for group 2\n');
            end
            
            if group==1
                C2C = app.C2C_stat_g1_des;
            elseif group==2
                C2C = app.C2C_stat_g2_des;
            end
            assignin('base','C2C_stat_g1_des',app.C2C_stat_g1_des);
            R_3d = C2C.R;
            clusternames = C2C.clusternames;
            G.R_3d = R_3d;
            % berechne Korrelation zweichen Person und behavioralen Daten
            [G.RR, G.RP] = get_conn_R_B(app,R_3d, B);
            
            if isfield(C2C,'Syn')
                G.Syn_3d = C2C.Syn;
                % berechne Korrelation zwischen Synchronization yund behavioralen Daten
                [G.SynR, G.SynP] = get_conn_R_B(app,G.Syn_3d, B);
            end
            if isfield(C2C,'PLV')
                G.PLV_3d = C2C.PLV;
                [G.PLVR, G.PLVP] = get_conn_R_B(app,G.PLV_3d, B);
            end
            if isfield(C2C,'T')
                G.T_4d = C2C.T;
                for i = 1:size(G.T_4d,2)
                    [G.TR(:,:,:,i), G.TP(:,:,:,i)] = get_conn_R_B(app,squeeze(G.T_4d(:,i,:,:)), B);
                end
            end
            
            try
            % Coherence
            if isfield(C2C,'Coh')
                G.Coh_4d = C2C.Coh;
                % frequenzen
                G.CohF = squeeze(C2C.CohF(1,:,1,1));
                for i = 1:size(G.Coh_4d,2)
                    [G.CohR(:,:,:,i), G.CohP(:,:,:,i)] = get_conn_R_B(app,squeeze(G.Coh_4d(:,i,:,:)), B);
                end
            end
            catch
                fprintf('problems with coherence estimation see workspace with input G_Coherr and B_err\n');
                G_Coherr = squeeze(G.Coh_4d(:,i,:,:));
                B_err = B;
                assignin('base','G_Coherr',G_Coherr);
                assignin('base','B_err',B_err);
                
            end
            
            % Power
            if isfield(C2C,'Power')
                G.Power = C2C.Power;
                [G.PowerR, G.PowerP] = get_conn_R2d_B(app,G.Power, B);
            end
            
            % Complexity
            if isfield(C2C,'Power')
                G.Power = C2C.Power;
                [G.PowerR, G.PowerP] = get_conn_R2d_B(app,G.Power, B);
            end
            % intranetwork
            if isfield(C2C,'RN')
                G.RN_2d = C2C.RN;
                % berechne Korrelation zwischen Synchronization yund behavioralen Daten
                [G.RNR, G.RNP] = get_conn_R2d_B(app,G.RN_2d, B);
                G.networknames = C2C.networknames;
            end
           % internetwork
            if isfield(C2C,'RiN')
                G.RiN_3d = C2C.RiN;
                % berechne Korrelation zwischen Synchronization yund behavioralen Daten
                [G.RiNR, G.RiNP] = get_conn_R_B(app,G.RiN_3d, B);
                % fuelle die diagonalelemente mit den RN netzwerken auf
                if isfield(G,'RNR')
                    for ii=1:size(G.RNR,1)
                        G.RiNR(ii,ii,:)=G.RNR(ii,:);
                        G.RiNP(ii,ii,:)=G.RNP(ii,:);
                        G.RiN_3d(ii,ii,:)=G.RN_2d(ii,:);
                        
                    end
                end
                G.networknames = C2C.networknames;
            end
 
            
            G.clusternames = clusternames;
            G.clusternames_org = clusternames;
            
        end
        
        function G = estimate_GroupDiff(app,G1,G2)
            
            
            % berechne eine gemeinsame Korrelationsmatrix zu den behavioralen Daten
            % erzeuge eine gemeinsames R R12 mit R1 links unten  und R2 rechts oben
            assignin('base','G1',G1);
            assignin('base','G2',G2);
            
            G.RR12 = G2.RR;
            for i=1:size(G2.RR,1)
                for j=1:i-1
                    G.RR12(i,j,:)=G1.RR(i,j,:);
                end
            end
            
            G.SynR12 = G2.SynR;
            for i=2:size(G1.SynR,1)
                G.SynR12(i,1:i-1,:)=G1.SynR(i,1:i-1,:);
            end
            G.PLVR12 = G2.PLVR;
            for i=2:size(G1.PLVR,1)
                G.PLVR12(i,1:i-1,:)=G1.PLVR(i,1:i-1,:);
            end
            %intranetwork
            G2.RNR
            G.RNR12 = zeros(size(G2.RNR,1),2);
            G.RNR12(:,2) =G2.RNR(:,1);
            G.RNR12(:,1) =G1.RNR(:,1);
            
            %internetwork
            G.RiNR12 = G2.RiNR;
            for i=1:size(G2.RiNR,1)
                for j=1:i-1
                    G.RiNR12(i,j,:)=G1.RiNR(i,j,:);
                end
            end

            
        end
        
        function [R,P] = get_conn_R2d_B(app,R_2d, B)
            
            
            R = zeros(size(R_2d,1),size(B,2));
            P = ones(size(R_2d,1),size(B,2));
            
            for i=1:size(R_2d,1)
                for k=1:size(B,2)
                    [R(i,k),P(i,k)]=corr(squeeze(R_2d(i,:))',squeeze(B(:,k)));
                end
            end
        end
        
        function [R,P] = get_conn_R_B(app,R_3d, B)
            
            R = zeros(size(R_3d,1),size(R_3d,2),size(B,2));
            P = ones(size(R_3d,1),size(R_3d,2),size(B,2));
            
            for i=1:size(R_3d,1)
                %for j=1:size(R_3d,2)
                for j=1:i-1
                    for k=1:size(B,2)
                        %size(R_3d)
                        %size(B)
                        [R(i,j,k),P(i,j,k)]=corr(squeeze(R_3d(i,j,:)),B(:,k));
                        R(j,i,k) = R(i,j,k);
                        P(j,i,k) = P(i,j,k);
                    end
                end
            end
            
            
        end

        function [varnamelistsel,BG1,BG2] = get_selected_exel_data(app)

            varnamelistall = app.ExelvariablesListBox.Items;
            varnamelistsel = app.ExelvariablesListBox.Value;
            
            tmp = ismember( varnamelistall,varnamelistsel)==1;
            varidxlist = find(tmp);

            [row_g1] = get_rows_of_subjects(app,app.IDlist,app.namelist1);
            [row_g2] = get_rows_of_subjects(app,app.IDlist,app.namelist2);
            
            %ID_sel_list = handles.IDlist;
            BG1 = cell2mat(app.exeldata(row_g1,varidxlist));
            BG2 = cell2mat(app.exeldata(row_g2,varidxlist));


        end
        
%         function test_for_g_1(app,R)
%             for i=1:size(R,1); for j=1:size(R,2); for k =1:size(R,3); if R(i,j,k)>1; fprintf(' %.0f  %.0f %.0f %.5f\n',i,j,k,R(i,j,k)); end; end; end; end;
%         end
        
        function results = func17(app)
            
        end
        
        function STAT = make_C2C_group_struct2(app,dir,namelist,postfix)
            % postfix definiert die Daten die rausgeschrieben werden Y Yc1, Yc2
            h=waitbar(0,'Please wait');
            
            % gibt es eine Netzwerkdefinition ?
            load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
            if isfield(Cluster,'net')
                net =1;
            end
            
            load(fullfile(dir,namelist{1,1}));
            STAT.C2C.PCC.R=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            STAT.C2C.PCC.P=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            STAT.C2C.PCC.Z=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            STAT.C2C.SYN.R=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            %C2C.T=zeros(size(Cluster,1),1,size(Cluster,1),length(namelist));
            STAT.C2C.PLV.R=zeros(size(Cluster,1),size(Cluster,1),length(namelist));
            STAT.C2C.POW.R2d=zeros(size(Cluster,1),length(namelist));
            clusterXYZ = cell(0);
            for i=1:length(namelist)
                waitbar(i / length(namelist));
                fprintf('load Cluster %s \n',fullfile(dir,namelist{i}));
                load(fullfile(dir,namelist{i}));
                % die Clusternamen und Koordinaten werden aus einem
                % Cluster extrahiert (alle Cluster müssen natürlich gleich sein)
                %fprintf('%d\n',size(Cluster,1));
                %fprintf('loading %s\n',namelist{i});
                for j=1:size(Cluster,1)
                    clusternames{j,1}=Cluster{j,1}.name;
                    if isfield(Cluster{j,1},'XYZ')
                        clusterXYZ{j,1}=Cluster{j,1}.XYZ;
                    end
                    %assignin('base','Cluster',Cluster);
                    if ~isfield(Cluster{j,1},'Cluster2Cluster')
                        error('please estimate C2C connectivity first (no Cluster2Cluster field in Cluster struct)');
                        
                    end
                   
                    if isfield(Cluster{j,1}.Cluster2Cluster,['R', postfix])
                        STAT.C2C.PCC.R(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['R', postfix]);
                        STAT.C2C.PCC.P(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['P', postfix]);
                        STAT.C2C.PCC.Z(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['Z', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['T', postfix]))
                        Tx = Cluster{j,1}.Cluster2Cluster.(['T', postfix]);
                        STAT.C2C.TRA.T(:,1:size(Tx,1),j,i)=Tx';
                    end
                    % hier manchmal komplexe Zahlen ... evtl bei merkwuerdiegen Y_mean eingabe
                    try
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Coh', postfix]))
                        Coh = Cluster{j,1}.Cluster2Cluster.(['Coh', postfix]);
                        CohF = Cluster{j,1}.Cluster2Cluster.(['CohF', postfix]);
                        STAT.C2C.COH.Coh(:,1:size(Coh,2),j,i)=Coh;
                        STAT.C2C.COH.Cohf(:,1:size(Coh,2),j,i)=CohF;
                    end
                    catch
                        fprintf('choerence konnte nicht berechnet werden warscheinlich Y_mean ungeeignet\n');
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Syn', postfix]))
                        STAT.C2C.SYN.R(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['Syn', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['PLV', postfix]))
                        STAT.C2C.PLV.R(:,j,i)=Cluster{j,1}.Cluster2Cluster.(['PLV', postfix]);
                    end
                    if isfield(Cluster{j,1}.Cluster2Cluster,(['Comp', postfix]))
                        STAT.C2C.COM.R(j,i)=Cluster{j,1}.Cluster2Cluster.(['Comp', postfix]);
                    end
                    Y_mean = Cluster{j,1}.(['Y', postfix, '_mean']);
                    STAT.C2C.POW.Power(j,i)=sum(Y_mean.^2)/(length(Y_mean)*2+1);
                    
                    % Netzwerk einfuegen 
                    % STAT.RiN
                    % STAT.ZiN
                    
                end
                
            end
            close(h)
            % die jeweilige Variable hat noch eine beschreibung mit dem Namen der Variablen + des
            STAT.C2C.PCC.des{1} = 'R ... Pearson correlation';
            STAT.C2C.COH.des{1} = 'Coh ... Coherence value';
            STAT.C2C.SYN.des{1} = 'Synchronicity value';
            STAT.C2C.PLV.des{1} = 'R ... PLV value';
            STAT.C2C.COM.des{1} = 'R ... Complexity value';
            STAT.C2C.POW.des{1} = 'Power ... Power value';
            STAT.C2C.TRA.des{1} = 'T ... Transinformation';
            STAT.C2C.clusternames=clusternames;
            STAT.C2C.clusterXYZ=clusterXYZ;
            STAT.namelist = namelist;
        end
        
        function C2C_stat_g1 = deslect_subjects_ck2(app,C2C_stat_g1,namelist,idx)
            %fprintf('deselect subjects\n');
            if length(idx)==0
                return;
            end
            %C2C_stat_g1.Z
            f = fields(C2C_stat_g1);
            for i=1:length(f)
                if isstruct(C2C_stat_g1.(f{i}))
                    f2 = fields(C2C_stat_g1.(f{i}));
                    for j = 1:length(f2)
                        if isstruct(C2C_stat_g1.(f{i}).(f2{j}))
                            C2C_stat_g1.(f{i}).f2{j}=reduce(C2C_stat_g1.(f{i}).(f2{j}),idx);
                        end
                    end
                end
            end
            
        end
        
        function C = reduce(C,idx,num_subjects)
            f = fields(C);
            for i=1:length(f)
                t = size(C.(f{i}));
                dims = lenth(t);
                if isnumeric(C.(f{i})) && t(end)==num_subjects
                    if dims== 1
                        C.(f{i})(idx)=[];
                    end
                    if dims == 2
                        C.(f{i})(:,idx)=[];
                    end
                    if dims == 3
                        C.(f{i})(:,:,idx)=[];
                    end
                    if dims == 4
                        C.(f{i})(:,:,:,idx)=[];
                    end
                end
            end
%             
%                 C2C_stat_g1.R(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'P'); C2C_stat_g1.P(:,:,idx)=[];  end
%             if isfield(C2C_stat_g1,'Z'); C2C_stat_g1.Z(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'Syn'); C2C_stat_g1.Syn(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'PLV'); C2C_stat_g1.PLV(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'T'); C2C_stat_g1.T(:,:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'Coh'); C2C_stat_g1.Coh(:,:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'CohF'); C2C_stat_g1.CohF(:,:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'Power'); C2C_stat_g1.Power(:,idx)=[]; end
%             if isfield(C2C_stat_g1,'Comp'); C2C_stat_g1.Comp(:,idx)=[]; end
%             if isfield(C2C_stat_g1,'RiN'); C2C_stat_g1.RiN(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'RN'); C2C_stat_g1.RN(:,idx)=[]; end
%             if isfield(C2C_stat_g1,'ZiN'); C2C_stat_g1.ZiN(:,:,idx)=[]; end
%             if isfield(C2C_stat_g1,'ZN'); C2C_stat_g1.ZN(:,idx)=[]; end
%             if isfield(C2C_stat_g1,'namelist'); C2C_stat_g1.namelist = namelist; end
%             


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Buttonchooseg1dir
        function Buttonchooseg1dirPushed(app, event)
            p = uigetdir('Select  dir for Clusterdata of group 1');
            app.g1dirEditField.Value = p;
        end

        % Button pushed function: Buttonchooseresultsdir
        function ButtonchooseresultsdirPushed(app, event)
            p = uigetdir('Select root dir for results and Cluster struct');
            app.resultsdirEditField.Value = p;
        end

        % Button pushed function: C2CBCsigdiffButton
        function C2CBCsigdiffButtonPushed(app, event)
            %             figure('Name',' significant differences ','NumberTitle','off');
            %             imagesc(app.C2C_stat.Zh);
            %             %ck_rc2_adjust_C2C_figure(handles.C2C_stat_g1.clusternames);
        end

        % Button pushed function: C2CchooseexelfileButton
        function C2CchooseexelfileButtonPushed(app, event)
            [f,p]=uigetfile('*.*');
            app.xlscsvFileEditField.Value = fullfile(p,f);
        end

        % Button pushed function: C2CestimateClusterstatisticButton
        function C2CestimateClusterstatisticButtonPushed(app, event)
            resultsdir = app.resultsdirEditField.Value;
            dir1=app.g1dirEditField.Value;
            dir2=app.g1dirEditField.Value;
            
            %erstelle die zu übergebende Datenstruktur
            if app.C2CtwosampleButton.Value
                method = 2;
            elseif app.C2CpairedButton.Value
                method = 3;
            end
            
            unsel_g1 = get_unselected_idx(app,app.C2Cgroup1ListBox);
            unsel_g2 = get_unselected_idx(app, app.C2Cgroup2ListBox);
            
            
            app.namelist1 = app.C2Cgroup1ListBox.Value;
            app.namelist2 = app.C2Cgroup2ListBox.Value;
            
            [app.C2C_stat_g1_des] = deslect_subjects_ck(app, app.C2C_stat_g1_org, app.namelist1, unsel_g1);
            [app.C2C_stat_g2_des] = deslect_subjects_ck(app, app.C2C_stat_g2_org, app.namelist2, unsel_g2);


            data=struct;
            if isempty(app.namelist1)
                msgbox('you have to selct at least one subject of group1');
                return
            else
                numgroup=1;
                assignin('base','C2C_stat_g1_des',app.C2C_stat_g1_des);
                % Uebertragung aller Korrelationsmasse von Gruppe 1
                if isfield(app.C2C_stat_g1_des,'R'); data.R1=app.C2C_stat_g1_des.R;  end
                if isfield(app.C2C_stat_g1_des,'P'); data.P1=app.C2C_stat_g1_des.P;  end
                if isfield(app.C2C_stat_g1_des,'Z'); data.Z1=app.C2C_stat_g1_des.Z;  end
                if isfield(app.C2C_stat_g1_des,'Syn'); data.Syn1=app.C2C_stat_g1_des.Syn;  end
                if isfield(app.C2C_stat_g1_des,'PLV'); data.PLV1=app.C2C_stat_g1_des.PLV;  end
                if isfield(app.C2C_stat_g1_des,'T'); data.T1=app.C2C_stat_g1_des.T; end
                if isfield(app.C2C_stat_g1_des,'Coh'); data.Coh1=app.C2C_stat_g1_des.Coh;  end
                if isfield(app.C2C_stat_g1_des,'CohF'); data.CohF1=app.C2C_stat_g1_des.CohF;  end
                if isfield(app.C2C_stat_g1_des,'Comp'); data.Comp1=app.C2C_stat_g1_des.Comp; end
                if isfield(app.C2C_stat_g1_des,'Power'); data.Power1=app.C2C_stat_g1_des.Power;  end
                if isfield(app.C2C_stat_g1_des,'RN'); data.RN1=app.C2C_stat_g1_des.RN;  end
                if isfield(app.C2C_stat_g1_des,'RiN'); data.RiN1=app.C2C_stat_g1_des.RiN;  end
                if isfield(app.C2C_stat_g1_des,'ZN'); data.ZN1=app.C2C_stat_g1_des.ZN;  end
                if isfield(app.C2C_stat_g1_des,'ZiN'); data.ZiN1=app.C2C_stat_g1_des.ZiN;  end
                if isfield(app.C2C_stat_g1_des,'GNM'); data.GNM1=app.C2C_stat_g1_des.GNM;  end
                if isfield(app.C2C_stat_g1_des,'GiNM'); data.GiNM1=app.C2C_stat_g1_des.GiNM;  end
            end
            if isempty(app.namelist2)
                msgbox('you have to select at least one dataset of group 2');
                return
            else
                numgroup=2;
                if isfield(app.C2C_stat_g2_des,'R'); data.R2=app.C2C_stat_g2_des.R;  end
                if isfield(app.C2C_stat_g2_des,'P'); data.P2=app.C2C_stat_g2_des.P;  end
                if isfield(app.C2C_stat_g2_des,'Z'); data.Z2=app.C2C_stat_g2_des.Z;  end
                if isfield(app.C2C_stat_g2_des,'Syn'); data.Syn2=app.C2C_stat_g2_des.Syn;  end
                if isfield(app.C2C_stat_g2_des,'PLV'); data.PLV2=app.C2C_stat_g2_des.PLV;  end
                if isfield(app.C2C_stat_g2_des,'T'); data.T2=app.C2C_stat_g2_des.T;  end
                if isfield(app.C2C_stat_g2_des,'Coh'); data.Coh2=app.C2C_stat_g2_des.Coh;  end
                if isfield(app.C2C_stat_g2_des,'CohF'); data.CohF2=app.C2C_stat_g2_des.CohF;  end
                if isfield(app.C2C_stat_g2_des,'Comp'); data.Comp2=app.C2C_stat_g2_des.Comp; end
                if isfield(app.C2C_stat_g2_des,'Power'); data.Power2=app.C2C_stat_g2_des.Power;  end
                if isfield(app.C2C_stat_g2_des,'RN'); data.RN2=app.C2C_stat_g2_des.RN;  end
                if isfield(app.C2C_stat_g2_des,'RiN'); data.RiN2=app.C2C_stat_g2_des.RiN;  end
                if isfield(app.C2C_stat_g2_des,'ZN'); data.ZN2=app.C2C_stat_g2_des.ZN;  end
                if isfield(app.C2C_stat_g2_des,'ZiN'); data.ZiN2=app.C2C_stat_g2_des.ZiN;  end
                if isfield(app.C2C_stat_g1_des,'GNM'); data.GNM2=app.C2C_stat_g1_des.GNM;  end
                if isfield(app.C2C_stat_g1_des,'GiNM'); data.GiNM2=app.C2C_stat_g1_des.GiNM;  end           
            end            
            assignin('base','data1',data);
            unc = 0.05;
            fdr = 0.05;
            fwe = 0.05;
            % Festlegung welche Daten ... Y oder Yc1 oder Yc2
            
              
            normalize = app.C2CnormalizeCheckBox.Value;
            C2C_stat=ck_rc3_estimate_C2C_statistic(resultsdir,unc,fdr,fwe,data,numgroup,method,normalize);
            
            assignin('base','datax',data);
            % Berechnung der Netzwerkkonnektivitaet falls diese zuvor spezifiziert
            % wurde (Cluster{i}.group)
            load(fullfile(resultsdir,'Cluster.mat'));
            %if isfield(Cluster{1},'group')
            if isfield(Cluster{1},'net')
                Network=ck_rc3_estimate_C2C_network_groups_statistic(resultsdir,unc,fdr,fwe,...
                    data,numgroup,method,normalize,app.C2C_stat,Cluster);
               Network2=ck_rc3_estimate_C2C_network_groups_statistic2(resultsdir,unc,fdr,fwe,...
                    data,numgroup,method,normalize,app.C2C_stat,Cluster);
            end
            %C2C_stat = C2C_stat;        
            C2C_stat.Network=Network;
            C2C_stat.Network2=Network2;
            C2C_stat.clusternames=app.C2C_stat_g1.clusternames;
            app.C2C_stat=C2C_stat;
            %app.C2C_stat = app.C2C_stat;
            assignin('base','C2C_stat',C2C_stat);
            save(fullfile(resultsdir,'C2C_stat.mat'),'C2C_stat');
            
            assignin('base','C2C_stat',C2C_stat);
            app.C2CestimatedataLamp.Color = 'green';
        end

        % Button pushed function: 
        % C2CestimateClusterstatisticButton_2
        function C2CestimateClusterstatisticButton_2Pushed(app, event)
            resultsdir = app.resultsdirEditField.Value;
            %dir1=app.g1dirEditField.Value;
            %dir2=app.g2dirEditField.Value;
            
            %erstelle die zu übergebende Datenstruktur
            if app.C2CtwosampleButton.Value
                method = 2;
            elseif app.C2CpairedButton.Value
                method = 3;
            end
            
            unsel_g1 = get_unselected_idx(app,app.C2Cgroup1ListBox);
            unsel_g2 = get_unselected_idx(app, app.C2Cgroup2ListBox);
            
            
            app.namelist1 = app.C2Cgroup1ListBox.Value;
            app.namelist2 = app.C2Cgroup2ListBox.Value;
            
            [app.C2C_stat_g1_des] = deslect_subjects_ck2(app, app.C2C_stat_g1_org, app.namelist1, unsel_g1);
            [app.C2C_stat_g2_des] = deslect_subjects_ck2(app, app.C2C_stat_g2_org, app.namelist2, unsel_g2);


            data=struct;
            if isempty(app.namelist1) || isempty(app.namelist2)
                msgbox('you have to selct at least one subject of each group');
                return
            else
                numgroup=2;
            end


            normalize = app.C2CnormalizeCheckBox.Value;
            C2C_stat=ck_rc3_estimate_C2C_statistic2(resultsdir,app.C2C_stat_g1_des,app.C2C_stat_g2_des,numgroup,method,normalize);
            %               unc = 0.05;
            %             fdr = 0.05;
            %             fwe = 0.05;
            %             assignin('base','C2C_stat',C2C_stat);
            %             % Oberflaeche
            %             %C2C_stat.Oberflaeche = .... % noch einzufuegen
            %             % Berechnung der Netzwerkkonnektivitaet falls diese zuvor spezifiziert
            %             % wurde (Cluster{i}.group)
            %             load(fullfile(resultsdir,'Cluster.mat'));
            %             %if isfield(Cluster{1},'group')
            %             if isfield(Cluster{1},'net')
            %                 IntraNetwork=ck_rc3_estimate_C2C_network_groups_statistic(resultsdir,unc,fdr,fwe,...
            %                     data,numgroup,method,normalize,app.C2C_stat,Cluster);
            %                Network2=ck_rc3_estimate_C2C_network_groups_statistic2(resultsdir,unc,fdr,fwe,...
            %                     data,numgroup,method,normalize,app.C2C_stat,Cluster);
            %                Network3=ck_rc3_estimate_C2C_network_groups_statistic3(resultsdir,unc,fdr,fwe,...
            %                     data,numgroup,method,normalize,app.C2C_stat,Cluster);
            %             end
            %             %C2C_stat = C2C_stat;        
            %             C2C_stat.Network=Network3;
            %             %C2C_stat.Network2=Network2;
             %            C2C_stat.C2C.clusternames=app.C2C_stat_g1.clusternames;
            %             %app.C2C_stat=C2C_stat;
             app.C2C_stat = C2C_stat;
            %             assignin('base','C2C_stat',C2C_stat);
             save(fullfile(resultsdir,'C2C_stat.mat'),'C2C_stat');
            %             save(fullfile(resultsdir,'IntraNetwork.mat'),'IntraNetwork');
            %               
            assignin('base','C2C_stat',C2C_stat);
            app.C2CestimatedataLamp.Color = 'green';
        end

        % Button pushed function: C2CestimatecorrelationButton
        function C2CestimatecorrelationButtonPushed(app, event)
            fprintf('estimate exel correlation');
            [varnamelistsel,BG1,BG2] = get_selected_exel_data(app);
            load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            load(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
            parameter.behavioraldata = [BG1 ;BG2];
            parameter.behavioraltitle = varnamelistsel;
            save(fullfile(app.resultsdirEditField.Value,'parameter.mat'),'parameter');
            
            h=waitbar(0,'Please wait estimating');
            waitbar(1 / 4);
            [G1] = get_Rg(app,app.resultsdirEditField.Value,1,BG1);
            G1.BG = BG1;
            G1.TG = varnamelistsel;
            waitbar(2/4);
            [G2] = get_Rg(app,app.resultsdirEditField.Value,2,BG2);
            G2.BG = BG2;
            G2.TG = varnamelistsel;
            waitbar(3/4);
            G = estimate_GroupDiff(app,G1,G2);
            G.G1 = G1;
            G.G2 = G2;
            G.clusternames = C2C_stat.clusternames;
            G.clusternames_org = G.clusternames;
            G.networknames = C2C_stat.Network2.networknames';
            % waitbar(1);
            waitbar(4/4);
            
            C2C_stat.G = G;
            save(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'),'C2C_stat');
            app.C2C_stat = C2C_stat;
            close(h);
            app.C2CestimateexelcorrelationLamp.Color = 'green';
            
        end

        % Button pushed function: 
        % C2CestimatenetworkcorrelationButton
        function C2CestimatenetworkcorrelationButtonPushed(app, event)
            fprintf('estimate exel correlation');
            [varnamelistsel,BG1,BG2] = get_selected_exel_data(app);
            load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            load(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
            parameter.behavioraldata = [BG1 ;BG2];
            parameter.behavioraltitle = varnamelistsel;
            save(fullfile(app.resultsdirEditField.Value,'parameter.mat'),'parameter');
            
            h=waitbar(0,'Please wait estimating');
            waitbar(1 / 4);
            [G1] = get_Rg(app,app.resultsdirEditField.Value,1,BG1);
            G1.BG = BG1;
            G1.TG = varnamelistsel;
            waitbar(2/4);
            [G2] = get_Rg(app,app.resultsdirEditField.Value,2,BG2);
            G2.BG = BG2;
            G2.TG = varnamelistsel;
            waitbar(3/4);
            G = estimate_GroupDiff(app,G1,G2);
            G.G1 = G1;
            G.G2 = G2;
            G.clusternames = C2C_stat.clusternames;
            G.clusternames_org = G.clusternames;
            G.networknames = C2C_stat.Network2.networknames';
            % waitbar(1);
            waitbar(4/4);
            
            C2C_stat.G = G;
            save(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'),'C2C_stat');
            app.C2C_stat = C2C_stat;
            close(h);
            app.C2CestimateexelcorrelationLamp.Color = 'green';
            
        end

        % Button pushed function: C2CexelreadmeButton
        function C2CexelreadmeButtonPushed(app, event)
            readme{1,1} = 'Behavioral data\n';
            readme{2,1} = '-----------------------------------------------------------\n';
            readme{3,1} = 'Datenformat\n';
            readme{4,1} = 'Daten werden als Exel file uebergeben\n';
            readme{5,1} = 'die erste Zeile Kennzeichnet die Varibalennamen \n';
            readme{6,1} = 'Eine Spalte muss den Titel "ID" tragen\n';
            readme{7,1} = 'Diese ID wird mit den letzten 4 Ziffern der Datensatznamen\n';
            readme{8,1} = 'verglichen und die entsprechenden Werte den Probanden/Patienten\n';
            readme{9,1} = 'Zugeordnet\n';
            
            txt = '';
            for i=1:size(readme,1)
                txt = [txt readme{i,1}]; %#ok<AGROW>
            end
            helpdlg(sprintf(txt));
        end

        % Button pushed function: C2CloaddataButton
        function C2CloaddataButtonPushed(app, event)
            
            load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
            
            [app.namelist1,app.namelist2] = get_all_selected_filenames(app,'part');
            resultsdir = app.resultsdirEditField.Value;
            dir1=app.g1dirEditField.Value;
            dir2=app.g2dirEditField.Value;
            namelistpre1=app.namelist1;
            namelistpre2=app.namelist2;
            if app.C2CYButton.Value; choosedata = 'Y';  postfix = ''; end
            if app.C2CYc1Button.Value; choosedata = 'Yc1';  postfix = 'c1'; end
            if app.C2CYc2Button.Value; choosedata = 'Yc2';  postfix = 'c2'; end
            
            if app.C2CtwosampleButton.Value
                if ~isempty(namelistpre1)
                    assignin('base','dir1',dir1)
                    app.C2C_stat_g1=make_C2C_group_struct(app,dir1,app.namelist1,postfix);
                    app.C2C_stat_g1.method = 2;
                    if isfield(Cluster{1},'net')
                        app.C2C_stat_g1 = ck_rc3_get_C2C_network_values(resultsdir,app.C2C_stat_g1,Cluster);
                    end
                    app.C2C_stat_g1_org = app.C2C_stat_g1;
                    C2C_stat_g1 = app.C2C_stat_g1;
                    save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g1.mat'),'C2C_stat_g1');
                end
                % nochmal für die zweite Gruppe falls gewählt
                if ~isempty(namelistpre2)
                    app.C2C_stat_g2=make_C2C_group_struct(app,dir2,app.namelist2,postfix);
                    app.C2C_stat_g2.method = 2;
                    if isfield(Cluster{1},'net')
                        app.C2C_stat_g2 = ck_rc3_get_C2C_network_values(resultsdir,app.C2C_stat_g2,Cluster);
                    end
                    app.C2C_stat_g2_org = app.C2C_stat_g2;
                    C2C_stat_g2 = app.C2C_stat_g2;
                    save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g2.mat'),'C2C_stat_g2');
                    
                end
            end
            
            %%%%%%%%%%%%%%%%%
            %%% paired t-test
            if app.C2CpairedButton.Value
                fprintf('paired t-test not adpated .. a little work...\n');
            %                 
            %                 %%%%%%modified sb 28.07.2012
            %                 if get(handles.radiobuttonpairedgroups,'Value')
            %                     handles.method=3;
            %                     dir1=get(handles.editdatadirg1,'String');
            %                     dir2=get(handles.editdatadirg2,'String');
            %                     gesetzt=0;
            %                     if isfield(parameter,'C2Ccontrolparameter')
            %                         
            %                         if isfield(parameter.C2Ccontrolparameter,'pairedgroup1')
            %                             choice = questdlg('soll die alte paired liste verwendet werden:','load previous', 'Yes', 'No','No');
            %                             % Handle response
            %                             switch choice
            %                                 case 'Yes'
            %                                     app.namelist1=parameter.C2Ccontrolparameter.pairedgroup1;
            %                                     app.namelist2=parameter.C2Ccontrolparameter.pairedgroup2;
            %                                     gesetzt=1;
            %                                     
            %                                 case 'No'
            %                                     
            %                             end
            %                         end
            %                     end
            %                     % wenn noch nicht eingetragen dann frage ab
            %                     if ~gesetzt
            %                         [t1,sts1]=spm_select([1 Inf],'mat',...
            %                             'Please select C2C_* Files in the correct (paired) oder ',[],dir1,'Cluster_');
            %                         [t2,sts2]=spm_select(size(t1,1),'mat',...
            %                             'Please select C2C_* Files in the correct (paired) oder ',[],dir2,'Cluster_');
            %                         for i=1:size(t1,1)
            %                             [p1 f1 e1 ] = fileparts(t1(i,:));
            %                             [p2 f2 e2 ] = fileparts(t2(i,:));
            %                             app.namelist1{i,1}=[f1 '.mat'];
            %                             app.namelist2{i,1}=[f2 '.mat'];
            %                         end
            %                     end
            %                     %     tmp2name=dir(fullfile(dir2,'AAL_*.mat'));
            %                     %     for i=1:size(tmp2name,1)
            %                     %         app.namelist2{i,1}=tmp2name(i,1).name;
            %                     %     end
            %                     assignin('base','app.namelist1',app.namelist1);
            %                     assignin('base','app.namelist2',app.namelist2);
            %                     set(handles.textg1subjects,'String',num2str(size(app.namelist1,1)));
            %                     set(handles.textg2subjects,'String',num2str(size(app.namelist2,1)));
            %                     namelistpre1=app.namelist1;
            %                     namelistpre2=app.namelist2;
            %                     handles.C2C_stat_g1=make_C2C_group_struct(dir1,app.namelist1);
            %                     handles.C2C_stat_g2=make_C2C_group_struct(dir2,app.namelist2);
            %                     % erstelle stuct nur zum Abspeichern
            %                     C2C_stat_g1=struct;
            %                     C2C_stat_g2=struct;
            %                     C2C_stat_g1=handles.C2C_stat_g1;C2C_stat_g1.method = handles.method;
            %                     C2C_stat_g2=handles.C2C_stat_g2;C2C_stat_g2.method = handles.method;
            %                     handles.C2C_stat_g1_org = C2C_stat_g1;
            %                     handles.C2C_stat_g2_org = C2C_stat_g2;
            %                     save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g1.mat'),'C2C_stat_g1');
            %                     save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g2.mat'),'C2C_stat_g2');
            %                 end
            end
            %%% end modified sb 28.07.2012
            
            app.C2Cgroup1ListBox.Items = app.namelist1;
            app.C2Cgroup2ListBox.Items = app.namelist2;
            
            app.C2CloaddataLamp.Color = 'green';
            
            
            % speichere die Eintragungen zur späteren Verwendung
            % bei erneuten Aufrufen der Gui damit diese dann sofort automatisch
            % eingetragen werden können
            %             load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            %             parameter.C2Ccontrolparameter.dir1=get(app.g1dirEditField.Value);
            %             parameter.C2Ccontrolparameter.dir2=get(handles.editdatadirg2,'String');
            %             parameter.C2Ccontrolparameter.radiobuttononegroup=get(handles.radiobuttononegroup,'Value');
            %             parameter.C2Ccontrolparameter.radiobuttontwogroups=get(handles.radiobuttontwogroups,'Value');
            %             parameter.C2Ccontrolparameter.radiobuttonpairedgroups=get(handles.radiobuttonpairedgroups,'Value');
            %             parameter.C2Ccontrolparameter.editg1datafile=handles.editg1datafile.String;
            %             parameter.C2Ccontrolparameter.editg1titlefile=handles.editg1titlefile.String;
            %             parameter.C2Ccontrolparameter.editg2datafile=handles.editg2datafile.String;
            %             parameter.C2Ccontrolparameter.editg2titlefile=handles.editg2titlefile.String;
            %             save(fullfile(app.resultsdirEditField.Value,'parameter.mat'),'parameter');
            %            guidata(hObject,handles);
            
        end

        % Button pushed function: C2CloaddataButton_2
        function C2CloaddataButton_2Pushed(app, event)
            load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
            
            [app.namelist1,app.namelist2] = get_all_selected_filenames(app,'part');
            resultsdir = app.resultsdirEditField.Value;
            dir1=app.g1dirEditField.Value;
            dir2=app.g2dirEditField.Value;
            namelistpre1=app.namelist1;
            namelistpre2=app.namelist2;
            if app.C2CYButton.Value; choosedata = 'Y';  postfix = ''; end
            if app.C2CYc1Button.Value; choosedata = 'Yc1';  postfix = 'c1'; end
            if app.C2CYc2Button.Value; choosedata = 'Yc2';  postfix = 'c2'; end
            
            if app.C2CtwosampleButton.Value
                if ~isempty(namelistpre1)
                    assignin('base','dir1',dir1)
                    app.C2C_stat_g1=make_C2C_group_struct2(app,dir1,app.namelist1,postfix);
                    app.C2C_stat_g1.method = 2;
                    if isfield(Cluster{1},'net')
                        app.C2C_stat_g1.NET = ck_rc3_get_C2C_network_values3(resultsdir,app.C2C_stat_g1,Cluster);
                    end
                    app.C2C_stat_g1_org = app.C2C_stat_g1;
                    C2C_stat_g1 = app.C2C_stat_g1;
                    save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g1v2.mat'),'C2C_stat_g1');
                end
                % nochmal für die zweite Gruppe falls gewählt
                if ~isempty(namelistpre2)
                    app.C2C_stat_g2=make_C2C_group_struct2(app,dir2,app.namelist2,postfix);
                    app.C2C_stat_g2.method = 2;
                    
                     if isfield(Cluster{1},'net')
                         app.C2C_stat_g2.NET = ck_rc3_get_C2C_network_values3(resultsdir,app.C2C_stat_g2,Cluster);
                     end
                    app.C2C_stat_g2_org = app.C2C_stat_g2;
                    C2C_stat_g2 = app.C2C_stat_g2;
                    save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g2v2.mat'),'C2C_stat_g2');
                    
                end
            end
            
            %%%%%%%%%%%%%%%%%
            %%% paired t-test
            if app.C2CpairedButton.Value
                fprintf('paired t-test not adpated .. a little work...\n');
            %                 
            %                 %%%%%%modified sb 28.07.2012
            %                 if get(handles.radiobuttonpairedgroups,'Value')
            %                     handles.method=3;
            %                     dir1=get(handles.editdatadirg1,'String');
            %                     dir2=get(handles.editdatadirg2,'String');
            %                     gesetzt=0;
            %                     if isfield(parameter,'C2Ccontrolparameter')
            %                         
            %                         if isfield(parameter.C2Ccontrolparameter,'pairedgroup1')
            %                             choice = questdlg('soll die alte paired liste verwendet werden:','load previous', 'Yes', 'No','No');
            %                             % Handle response
            %                             switch choice
            %                                 case 'Yes'
            %                                     app.namelist1=parameter.C2Ccontrolparameter.pairedgroup1;
            %                                     app.namelist2=parameter.C2Ccontrolparameter.pairedgroup2;
            %                                     gesetzt=1;
            %                                     
            %                                 case 'No'
            %                                     
            %                             end
            %                         end
            %                     end
            %                     % wenn noch nicht eingetragen dann frage ab
            %                     if ~gesetzt
            %                         [t1,sts1]=spm_select([1 Inf],'mat',...
            %                             'Please select C2C_* Files in the correct (paired) oder ',[],dir1,'Cluster_');
            %                         [t2,sts2]=spm_select(size(t1,1),'mat',...
            %                             'Please select C2C_* Files in the correct (paired) oder ',[],dir2,'Cluster_');
            %                         for i=1:size(t1,1)
            %                             [p1 f1 e1 ] = fileparts(t1(i,:));
            %                             [p2 f2 e2 ] = fileparts(t2(i,:));
            %                             app.namelist1{i,1}=[f1 '.mat'];
            %                             app.namelist2{i,1}=[f2 '.mat'];
            %                         end
            %                     end
            %                     %     tmp2name=dir(fullfile(dir2,'AAL_*.mat'));
            %                     %     for i=1:size(tmp2name,1)
            %                     %         app.namelist2{i,1}=tmp2name(i,1).name;
            %                     %     end
            %                     assignin('base','app.namelist1',app.namelist1);
            %                     assignin('base','app.namelist2',app.namelist2);
            %                     set(handles.textg1subjects,'String',num2str(size(app.namelist1,1)));
            %                     set(handles.textg2subjects,'String',num2str(size(app.namelist2,1)));
            %                     namelistpre1=app.namelist1;
            %                     namelistpre2=app.namelist2;
            %                     handles.C2C_stat_g1=make_C2C_group_struct(dir1,app.namelist1);
            %                     handles.C2C_stat_g2=make_C2C_group_struct(dir2,app.namelist2);
            %                     % erstelle stuct nur zum Abspeichern
            %                     C2C_stat_g1=struct;
            %                     C2C_stat_g2=struct;
            %                     C2C_stat_g1=handles.C2C_stat_g1;C2C_stat_g1.method = handles.method;
            %                     C2C_stat_g2=handles.C2C_stat_g2;C2C_stat_g2.method = handles.method;
            %                     handles.C2C_stat_g1_org = C2C_stat_g1;
            %                     handles.C2C_stat_g2_org = C2C_stat_g2;
            %                     save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g1.mat'),'C2C_stat_g1');
            %                     save(fullfile(app.resultsdirEditField.Value,'C2C_stat_g2.mat'),'C2C_stat_g2');
            %                 end
            end
            %%% end modified sb 28.07.2012
            
            app.C2Cgroup1ListBox.Items = app.namelist1;
            app.C2Cgroup2ListBox.Items = app.namelist2;
            
            app.C2CloaddataLamp.Color = 'green';


        end

        % Button pushed function: C2CmeanZdiffButton
        function C2CmeanZdiffButtonPushed(app, event)
            
            figure('Name',' MEan diff Matrix of dataset ','NumberTitle','off');
            app.C2C_stat
            imagesc(app.C2C_stat.C2C.MeanZdiff);
            %ck_rc2_adjust_C2C_figure(handles.C2C_stat_g1.clusternames);
        end

        % Button pushed function: C2CshownetworksButton
        function C2CshownetworksButtonPushed(app, event)
            fprintf('show networks\n');
            ck_rc3_show_networkgroup_results(app.resultsdirEditField.Value)
        end

        % Button pushed function: 
        % C2CvisualizenetworkcorrelationButton
        function C2CvisualizenetworkcorrelationButtonPushed(app, event)
            fprintf('C2CvisualizenetworkcorrelationButtonPushed');
            try
            [B.TG,B.BG1,B.BG2] = get_selected_exel_data(app);
            catch
                B = 0;
            end
            %             try
            %                 load(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
            %                 load(fullfile(app.resultsdirEditField.Value,'parameter.mat'));
            %             catch
            %                 msgbox('no C2C_stat2.mat file');
            %             end
            %             
            %             parameter.behavioraldata = [BG1 ;BG2];
            %             parameter.behavioraltitle = varnamelistsel;
            %             save(fullfile(app.resultsdirEditField.Value,'parameter.mat'),'parameter');
            %             B.BG1 = BG1;
            %             B.BG2 = BG2;
            %             B.TG = varnamelistsel;
            
            ck_rc3_visualize_network_clusterbehavior2(app.resultsdirEditField.Value,app.C2C_stat,B);
            
        end

        % Button pushed function: DefineClusterButton
        function DefineClusterButtonPushed(app, event)
            d = pwd;
            % speichere den ausgewaehlten Namen des Beispieldatensatzes im parameterfile ab
            f = fullfile(app.resultsdirEditField.Value,'parameter.mat');
            load(f);
            parameter.define_cluster_input_file = app.exemplarydatafileEditField.Value;
            save(f,'parameter');
            
            cd(app.resultsdirEditField.Value);
            
            sb_roi_cluster_manager();
            stat = listboxupdate(app);
            cd(d);
        end

        % Button pushed function: DefineNetworkButton
        function DefineNetworkButtonPushed(app, event)
            ck_rc3_define_network(app.resultsdirEditField.Value);
        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            fprintf('go for data import\n');
            options = struct;
            options.extract_all = app.extractallvoxelinClusterCheckBox.Value;
            options.extract_con1 = app.extractconditionalvoxelCheckBox.Value;
            options.extract_con1_g1_val = app.graymatterprobgreaterasEditField.Value;
            options.extract_con_g1_name = app.graymatterfilenameEditField.Value;
            options.extract_con1_g2_val = app.whitematterproblessthenEditField.Value;
            options.extract_con_g2_name = app.whitematterfilenameEditField.Value;
            options.extract_con1_g3_val = app.CSFproblessthenEditField.Value;
            options.extract_con_g3_name = app.csffilenameEditField.Value;
            options.extract_con2 = app.extractconditionalvoxelCheckBox.Value;
            options.extract_con2_g1_val = app.graymatterprobgreateras2EditField.Value;
            options.extract_con2_g2_val = app.whitematterproblessthen2EditField.Value;
            options.extract_con2_g3_val = app.CSFproblessthen2EditField.Value;
            
            
            %             m1file = get(handles.editmultig1,'String');
            %             load(m1file);
            %             Vmg1 = Vm;
            %             Pmg1 = Pm;
            %             m2file = get(handles.editmultig2,'String');
            %             load(m2file);
            %             Vmg2 = Vm;
            %             Pmg2 = Pm;
            %options = ck_rc2_fill_all_clusters_from_niftifiles_options(Vmg1,Pmg1,Vmg2,Pmg2);
            %options = ck_rc2_fill_all_clusters_from_niftifiles_options();
            parameterfile = fullfile(app.resultsdirEditField.Value,'parameter.mat');
            try 
                load(parameterfile)
            catch
                parameter = struct;
            end
                
            %hApp = ck_rc2_fill_all_clusters_from_niftifiles_options;
            %options = MySetupFunction(hApp, Vmg1,Pmg1,Vmg2,Pmg2,parameterfile);
            %[Vmg1,Pmg1]=ck_rc2_multisubject_selection();
            %[Vmg2,Pmg2]=ck_rc2_multisubject_selection();
            subjectfilelist_g1 = get_full_subjects_filenames(app,app.g1dirdataimportEditField.Value,app.g1dirdataimportListBox.Value);
            subjectfilelist_g2 = get_full_subjects_filenames(app,app.g2dirdataimportEditField.Value,app.g2dirdataimportListBox.Value);
            resultsdir = app.resultsdirEditField.Value;
            outputdirg1 = app.g1dirEditField.Value;
            outputdirg2 = app.g2dirEditField.Value;
            namelistg1 = app.g1dirdataimportListBox.Value;
            namelistg2 = app.g2dirdataimportListBox.Value;
            assignin('base','subjectfilelist_g1',subjectfilelist_g1);
            assignin('base','namelistg1',namelistg1);
            
            ck_rc3_fill_all_clusters_from_niftifiles(resultsdir,outputdirg1,subjectfilelist_g1,namelistg1,options);
            ck_rc3_fill_all_clusters_from_niftifiles(resultsdir,outputdirg2,subjectfilelist_g2,namelistg2,options);
            parameter.TR = app.TREditField.Value;
            parameter.lpf = app.lpfEditField.Value;
            parameter.hpf = app.hpfEditField.Value;
            save(parameterfile,'parameter');
            
        end

        % Button pushed function: chooseg2dir
        function chooseg2dirButtonPushed(app, event)
            p = uigetdir('Select  dir for Clusterdata of group 2');
            app.g2dirEditField.Value = p; 
        end

        % Value changed function: coherenceSwitch
        function coherenceSwitchValueChanged(app, event)
            value = str2num(app.coherenceSwitch.Value);
            if value
                app.coherencePanel.BackgroundColor = 'green';
            else
                app.coherencePanel.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Value changed function: complexitySwitch
        function complexitySwitchValueChanged(app, event)
            value = str2num(app.complexitySwitch.Value);
            if value
                app.complexityPanel.BackgroundColor = 'green';
            else
                app.complexityPanel.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Value changed function: correlationSwitch
        function correlationSwitchValueChanged(app, event)
            value = str2num(app.correlationSwitch.Value);
            if value
                app.correlationsPanel.BackgroundColor = 'green';
            else
                app.correlationsPanel.BackgroundColor = [0.94 0.94 0.94];
            end            
            
        end

        % Button pushed function: 
        % deletenetworkdefinitionsfromClusterstructButton
        function deletenetworkdefinitionsfromClusterstructButtonPushed(app, event)
            h=waitbar(0,'Please wait ...');
            f = fullfile(app.resultsdirEditField.Value,'Cluster.mat');
            if exist(f)
                
                load(f);
                Cluster = del_network(app,Cluster);
                waitbar(2/3);
                save(f,'Cluster','-v7.3');
            else
                msgbox('no Cluster.mat file found in results dir','error');
            end
            waitbar(3/3)
            close(h);
        end

        % Button pushed function: estimateconnectivityButton
        function estimateconnectivityButtonPushed(app, event)
            [filenamesg1] = app.g1ListBox.Value;
            [filenamesg2] = app.g2ListBox.Value;
            resultsdir = app.resultsdirEditField.Value;
            %get_all_selected_filenames(handles,'part');
            %assignin('base','file',filenamesg1);
            %[filenamesg1, filenamesg2] = get_filenames(handles,'Cluster_');
            %outdir = get(handles.editoutdir,'String');


            load(fullfile(resultsdir,'parameter.mat'));


            %options = ck_rc2_estimate_connectivity_options();
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %  erstelle die zu uebergebende options struct
            options.parameter = parameter;
            options.cancel = 0;
            options.EstimateCorrelations = str2num(app.correlationSwitch.Value);
            options.EstimateCoherence = str2num(app.coherenceSwitch.Value);
            options.EstimatePartialCoherence = str2num(app.partialcoherenceSwitch.Value);
            options.EstimateTransinformation = str2num(app.transinformationSwitch.Value);
            options.EstimateSynchronization = str2num(app.synchronizationSwitch.Value);
            options.EstimateComplexity = str2num(app.complexitySwitch.Value);
            options.Transinformation_bins = app.histogramEditField.Value;
            options.Transinformation_lag = app.timelagEditField.Value;
            options.Synchronization_bins = app.histogramsynchronizationEditField.Value;
            options.Complexity_bins = app.windowlengthcomplexityEditField.Value;
            options.Complexity_r = app.similaritymeasurecomplexityEditField.Value;
            options.Cluster2Voxel = app.Cluster2VoxelCheckBox.Value;
            options.C2V_c1mask = app.C1maskthresholdCheckBox.Value;
            options.C2V_threshold= app.thresholdEditField.Value;
            options.C2V_smoothing= app.smoothingCheckBox.Value;
            options.C2V_kernel= eval(app.kernelEditField.Value);
            options.Cluster2Cluster= app.Cluster2ClusterCheckBox.Value;
            options.Voxel2Voxel= app.Voxel2VoxelCheckBox.Value;
            options.useY = app.conuseYCheckBox.Value;
            options.useYc1 = app.conuseYc1CheckBox.Value;
            options.useYc2 = app.conuseYc2CheckBox.Value;
            
            
            % options struct fertig erstellt
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Uebergabe der Paramter an die Funktion zur Berechnung der Connectivitaet
            if  ~isempty(filenamesg1)
                ck_rc3_estimate_connectivity(resultsdir,app.g1dirEditField.Value,filenamesg1,options);
            end
            if  ~isempty(filenamesg2)
                ck_rc3_estimate_connectivity(resultsdir,app.g2dirEditField.Value,filenamesg2,options);
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % granger causality als extra aufruf
            
            if str2num(app.grangerSwitch.Value)
                
                GC_options = struct;
                GC_options.MultiSubjectAnalysis = 1;
                GC_options.EstimateCGCt = app.conditionalGCinthetimedomainCheckBox.Value;
                
                GC_options.nlagst = app.grangernumberoflagsEditField.Value;
                
                GC_options.pt = app.grangerpEditField.Value;
                GC_options.cort = 1;
                GC_options.EstimateCGCf = app.conditionalGCinfrequencydomainCheckBox.Value;
                GC_options.prep.detrend = app.grangerdetrenddataCheckBox.Value;
                GC_options.prep.removemean = app.grangerremovetemporalmeanCheckBox.Value;
                GC_options.prep.removemeanstd = app.grangerremovetemporalmeananddividebystdCheckBox.Value;
                GC_options.prep.differencing = app.grangerdifferencingtimeseriesCheckBox.Value;
                GC_options.prep.differencingit = app.grangeriterationEditField.Value;
                GC_options.EstimateGCautonomy = app.estimategrangerautonomyCheckBox.Value;
                GC_options.cluster2cluster = app.Cluster2ClusterCheckBox.Value;
                GC_options.cluster2voxel = app.Cluster2VoxelCheckBox.Value;
                GC_options.onegroup = 0;
                GC_options.twogroups = 1;
                GC_options.pairedgroups = 0;
                GC_options.freq = eval(app.grangerfrequencyEditField.Value);
                GC_options.prep.filter = 0;
                GC_options.prep.iffreqiteration = 0.01;
                GC_options.prep.freqiteration = app.grangerfrequencyiterationstepEditField.Value;
                
                GC_options.Cluster_sel_name = app.grangerselectedClusterListBox.Value;
                
                GC_options.Cluster_sel_num = length(options.Cluster_sel_name);
                CG_options.TR = parameter.TR;
                CG_options.parameter = parameter;
                % nun der Funktionsaufruf
                resultsdir = app.resultsdirEditField.Value;
                
                if  ~isempty(filenamesg1)
                    datadir = app.g1dirEditField.Value;
                    cGCt_groupA = ck_rc3_estimate_granger_causality(resultsdir,datadir,filenamesg1,GC_options);
                end
                if  ~isempty(filenamesg2)
                    datadir = app.g2dirEditField.Value;
                    cGCt_groupB = ck_rc3_estimate_granger_causality(resultsdir,datadir,filenamesg2,GC_options);
                end
                
                
            end
            % 
            % 
            % handles.output.MultiSubjectAnalysis = get(handles.radiobuttonmultisubjectanalysis,'Value');
            % handles.output.EstimateCGCt = get(handles.radiobuttonCGCtime,'Value');
            % handles.output.nlagst = str2double(get(handles.editnlagst,'String'));
            % handles.output.pt = str2double(get(handles.editpt,'String'));
            % handles.output.cort = (get(handles.popupmenucor,'Value'))-1;
            % handles.output.EstimateCGCf = get(handles.radiobuttonCGCfrequency,'Value');
            % handles.output.prep.detrend = get(handles.checkboxdetrend,'Value');
            % handles.output.prep.removemean = get(handles.checkboxremovetemporalmean,'Value');
            % handles.output.prep.removemeanstd = get(handles.checkboxremovetemporalmeanstdd,'Value');
            % handles.output.prep.differencing = get(handles.checkboxdifferencing,'Value');
            % handles.output.prep.differencingit = str2double(get(handles.editit,'String'));
            % handles.output.EstimateGCautonomy = get(handles.radiobuttongrangerautonomy,'Value');
            % handles.output.cluster2cluster = get(handles.radiobuttoncluster2cluster,'Value');
            % handles.output.cluster2voxel = get(handles.radiobuttoncluster2voxel,'Value');
            % handles.output.onegroup = get(handles.radiobuttononegroup,'Value');
            % handles.output.twogroups = get(handles.radiobuttontwogroups,'Value');
            % handles.output.pairedgroups = get(handles.radiobuttonpairedgroups,'Value');
            % handles.output.onegroupsingle = get(handles.radiobuttononegroupsingle,'Value');
            % handles.output.freq = eval((get(handles.editfrequency,'String')));
            % handles.output.prep.filter = get(handles.checkboxfreq,'Value');
            % handles.output.prep.iffreqiteration = get(handles.checkboxfreqiteration,'Value');
            % handles.output.prep.freqiteration = str2num(get(handles.editfreqiteration,'String'));
            % 
            % idx = get(handles.listbox1,'Value');
            % list = get(handles.listbox1,'String');
            % for i=1:size(idx,2);
            % handles.output.Cluster_sel_name{i} = list{idx(i)};
            % end
            % handles.output.Cluster_sel_num=idx;
            %             
            
        end

        % Button pushed function: exemplarydatadirButton
        function exemplarydatadirButtonPushed(app, event)
            [f,p] = uigetfile({'*.nii','*.img'},'Select nifti image file');
            app.exemplarydatafileEditField.Value = fullfile(p,f);
        end

        % Button pushed function: g1dirdataimportButton
        function g1dirdataimportButtonPushed(app, event)
            p = uigetdir('Select root dir for data of group 2');
            app.g1dirdataimportEditField.Value = p; 
        end

        % Button pushed function: g2dirdataimportButton
        function g2dirdataimportButtonPushed(app, event)
            p = uigetdir('Select root dir for data of group 2');
            app.g2dirdataimportEditField.Value = p; 
        end

        % Value changed function: grangerSwitch
        function grangerSwitchValueChanged(app, event)
            value = str2num(app.grangerSwitch.Value);
            if value
                app.GrangercausalitybigPanel.BackgroundColor = 'green';
                app.GrangercausalityPanel.BackgroundColor = 'green';
                try
                    load(fullfile(app.resultsdirEditField.Value,'Cluster.mat'));
                    for i=1:length(Cluster)
                        list{i} = Cluster{i}.name;
                    end
                    app.grangerselectedClusterListBox.Items = list;
                catch
                    msgbox('Cluster struct is needed');
                end
            else
                app.GrangercausalityPanel.BackgroundColor = [0.94 0.94 0.94];
                app.GrangercausalitybigPanel.BackgroundColor = [0.94 0.94 0.94];
            end            
        end

        % Button pushed function: initnewButton
        function initnewButtonPushed(app, event)
            d = app.resultsdirEditField.Value;
            f=fullfile(d,'parameter.mat');
            parameter = struct;
            if exist(f)
                answer = questdlg('parameter.mat exists ... overwrite?', 'Overwrite?', 'Yes','No','No');
                switch answer
                    case 'No'
                        return;
                end
            end
                save(f,'parameter');
                
            
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            cd(which('ck_rc3.mlapp'));
            [f,p]=uigetfile('select lastsessionfile');
            
            load(fullfile(p,f),'lastsession');
            app.resultsdirEditField.Value = lastsession.resultsdir;
            app.g1dirEditField.Value = lastsession.g1dir;
            app.g2dirEditField.Value = lastsession.g2dir;
            app.exemplarydatafileEditField.Value = lastsession.exemplarydatafile;
            app.g1dirdataimportEditField.Value = lastsession.g1dirdataimport;
            app.g2dirdataimportEditField.Value = lastsession.g2dirdataimport;
            app.dataimportsubdirnameEditField.Value = lastsession.dataimportsubdirname;
            app.extractallvoxelinClusterCheckBox.Value = lastsession.extractallvoxelinCluster;
            app.extractconditionalvoxelCheckBox.Value = lastsession.extractconditionalvoxel;
            app.extract2conditionalvoxelCheckBox.Value = lastsession.extract2conditionalvoxel;
            app.graymatterprobgreaterasEditField.Value = lastsession.graymatterprobgreateras;
            app.whitematterproblessthenEditField.Value = lastsession.whitematterproblessthen;
            app.CSFproblessthenEditField.Value = lastsession.CSFproblessthen;
            app.graymatterprobgreateras2EditField.Value = lastsession.graymatterprobgreateras2;
            app.whitematterproblessthen2EditField.Value = lastsession.whitematterproblessthen2;
            app.CSFproblessthen2EditField.Value = lastsession.CSFproblessthen2;
            app.graymatterfilenameEditField.Value = lastsession.graymatterfilename;
            app.whitematterfilenameEditField.Value = lastsession.whitematterfilename;
            app.csffilenameEditField.Value = lastsession.csffilename;
            app.dataimportfilefilterEditField.Value = lastsession.dataimportfilefilter;
            
            cd(app.resultsdirEditField.Value);
            
            stat = listboxupdate(app);
        end

        % Button pushed function: loadexelfileButton
        function loadexelfileButtonPushed(app, event)
            
%             [Lnum,Ltxt,L] = xlsread(app.xlscsvFileEditField.Value);
            [Lnum,Ltxt,L] = ck_rc3_xlsread_sb(app.xlscsvFileEditField.Value);
            
            titel = L(1,:);
            
            %skala = L(2,:);
            exeldata = L(2:end,:);
            % extrahiere die IDs
            id_col=find(strcmp(titel,'ID'));
            app.IDlist = cell2mat(exeldata(:,id_col));
            assignin('base','titel',titel);
            app.ExelvariablesListBox.Items = titel;
            %app.ExelvariablesListBox.
            
            %set(handles.listboxexelvariables,'Value',1);
            app.exeltitel = titel;
            app.exeldata = exeldata;
            
            
        end

        % Button pushed function: loadlastButton
        function loadlastButtonPushed(app, event)
            try
            [~,result] = dos('getmac');
            mac = result(160:176);
            catch 
                mac = 'no_mac';
            end
            try
                local_file = fullfile(fileparts(which('ck_rc3.mlapp')),['lastsession_' mac '.mat']);
                load(local_file,'lastsession');
                app.resultsdirEditField.Value = lastsession.resultsdir;
                app.g1dirEditField.Value = lastsession.g1dir;
                app.g2dirEditField.Value = lastsession.g2dir;
                app.exemplarydatafileEditField.Value = lastsession.exemplarydatafile;
                app.g1dirdataimportEditField.Value = lastsession.g1dirdataimport;
                app.g2dirdataimportEditField = lastsession.g2dirdataimport;
                app.dataimportsubdirnameEditField.Value = lastsession.dataimportsubdirname;
                app.extractallvoxelinClusterCheckBox.Value = lastsession.extractallvoxelinCluster;
                app.extractconditionalvoxelCheckBox.Value = lastsession.extractconditionalvoxel;
                app.extract2conditionalvoxelCheckBox.Value = lastsession.extract2conditionalvoxel;
                app.graymatterprobgreaterasEditField.Value = lastsession.graymatterprobgreateras;
                app.whitematterproblessthenEditField.Value = lastsession.whitematterproblessthen;
                app.CSFproblessthenEditField.Value = lastsession.CSFproblessthen;
                app.graymatterprobgreateras2EditField.Value = lastsession.graymatterprobgreateras2;
                app.whitematterproblessthen2EditField.Value = lastsession.whitematterproblessthen2;
                app.CSFproblessthen2EditField.Value = lastsession.CSFproblessthen2;
                app.graymatterfilenameEditField.Value = lastsession.graymatterfilename;
                app.whitematterfilenameEditField.Value = lastsession.whitematterfilename;
                app.csffilenameEditField.Value = lastsession.csffilename;
                app.dataimportfilefilterEditField.Value = lastsession.dataimportfilefilter;
                app.xlscsvFileEditField.Value = lastsession.exelfileeditfield;
                cd(app.resultsdirEditField.Value);
            catch
                fprintf('kein altes verzeichnis abgespeichert\n');
            end
            
            stat = listboxupdate(app);
        end

        % Value changed function: partialcoherenceSwitch
        function partialcoherenceSwitchValueChanged(app, event)
            value = str2num(app.partialcoherenceSwitch.Value);
            if value
                app.partialcoherencePanel.BackgroundColor = 'green';
            else
                app.partialcoherencePanel.BackgroundColor = [0.94 0.94 0.94];
            end
            
        end

        % Button pushed function: reduceClusterdataButton
        function reduceClusterdataButtonPushed(app, event)
            app.idleGauge.Value = 0;
            app.idleGaugeLabel.Text = 'working on cluster reduction';
            prefix = app.newprefixforClustermatfilesEditField.Value;
            
            [listg1,listg2] = get_all_selected_filenames(app,'full');
            reduce_Cluster(app,listg1,prefix);
            app.idleGauge.Value = 50;
            reduce_Cluster(app,listg2,prefix);
            app.idleGauge.Value = 100;
            app.idleGaugeLabel.Text = 'idle';
            
            
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            try
                [~,result] = dos('getmac');
                mac = result(160:176);
            catch
                mac = 'no_mac';
                fprintf('keine Mac adresse eruierbar\n');
            end
            
            local_file = fullfile(fileparts(which('ck_rc3.mlapp')),['lastsession_' mac '.mat']);
            lastsession = struct;
            lastsession.resultsdir =  app.resultsdirEditField.Value ;
            lastsession.g1dir = app.g1dirEditField.Value;
            lastsession.g2dir = app.g2dirEditField.Value;
            lastsession.exemplarydatafile = app.exemplarydatafileEditField.Value;
            lastsession.g1dirdataimport = app.g1dirdataimportEditField.Value;
            lastsession.g2dirdataimport = app.g2dirdataimportEditField.Value;
            lastsession.dataimportsubdirname = app.dataimportsubdirnameEditField.Value;
            lastsession.extractallvoxelinCluster =  app.extractallvoxelinClusterCheckBox.Value;
            lastsession.extractconditionalvoxel = app.extractconditionalvoxelCheckBox.Value;
            lastsession.extract2conditionalvoxel = app.extract2conditionalvoxelCheckBox.Value;
            lastsession.graymatterprobgreateras = app.graymatterprobgreaterasEditField.Value;
            lastsession.whitematterproblessthen = app.whitematterproblessthenEditField.Value ;
            lastsession.CSFproblessthen = app.CSFproblessthenEditField.Value ;
            lastsession.graymatterprobgreateras2 = app.graymatterprobgreateras2EditField.Value;
            lastsession.whitematterproblessthen2 = app.whitematterproblessthen2EditField.Value ;
            lastsession.CSFproblessthen2 = app.CSFproblessthen2EditField.Value ;
            lastsession.graymatterfilename = app.graymatterfilenameEditField.Value;
            lastsession.whitematterfilename = app.whitematterfilenameEditField.Value ;
            lastsession.csffilename = app.csffilenameEditField.Value ;
            lastsession.dataimportfilefilter =app.dataimportfilefilterEditField.Value  ;
            lastsession.exelfileeditfield = app.xlscsvFileEditField.Value;
            save(local_file,'lastsession');
            
            
        end

        % Value changed function: synchronizationSwitch
        function synchronizationSwitchValueChanged(app, event)
            value = str2num(app.synchronizationSwitch.Value);
            if value
                app.synchronizationPanel.BackgroundColor = 'green';
            else
                app.synchronizationPanel.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Button pushed function: testforfilepresenceButton
        function testforfilepresenceButtonPushed(app, event)
            
             %           g1pathlist
            %fprintf('testforfilepresencebutton\n');
            %Items = app.g1dirdataimportListBox.Items
            %            Val = app.g1dirdataimportListBox.Value
            r1 = test_existence(app,app.g1dirdataimportEditField.Value, app.g1dirdataimportListBox.Value);
            r2 = test_existence(app,app.g2dirdataimportEditField.Value, app.g2dirdataimportListBox.Value);
            
            if (r1+r2)==0
                app.Lamp.Color='green';
                
            else
                app.Lamp.Color='red';
            end
            
        end

        % Value changed function: transinformationSwitch
        function transinformationSwitchValueChanged(app, event)
            value = str2num(app.transinformationSwitch.Value);
            
            if value
                app.transinformationPanel.BackgroundColor = 'green';
            else
                app.transinformationPanel.BackgroundColor = [0.94 0.94 0.94];
            end
        end

        % Button pushed function: updateButton
        function updateButtonPushed(app, event)
            stat = listboxupdate(app);
        end

        % Button pushed function: updatedataimportButton
        function updatedataimportButtonPushed(app, event)
            p1 = app.g1dirdataimportEditField.Value;
            p2 = app.g2dirdataimportEditField.Value;
            filter = app.dataimportsubdirnameEditField.Value;
            listg1 = get_updatedataimport_list(app,p1,filter);
            listg2 = get_updatedataimport_list(app,p2,filter);
            app.g1dirdataimportListBox.Items = listg1;
            app.g2dirdataimportListBox.Items = listg2;
            
            
        end

        % Button pushed function: visualizecorrelationButton
        function visualizecorrelationButtonPushed(app, event)
            % lade C2C_stat datei
            try
            load(fullfile(app.resultsdirEditField.Value,'C2C_stat.mat'));
            catch
                msgdlg('no C2C_stat.mat file');
            end
            ck_rc3_visualize_network_clusterbehavior(app.resultsdirEditField.Value,C2C_stat.G);
            
        end

        % Button pushed function: loadnewsbButton
        function loadnewsbButtonPushed(app, event)
            ck_rc3_gui_load_sb(app);
        end

        % Button pushed function: savenewsbButton
        function savenewsbButtonPushed(app, event)
            ck_rc3_gui_save_sb(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ClusterDefinitionTab = uitab(app.TabGroup);
            app.ClusterDefinitionTab.Title = 'Cluster Definition';
            app.exemplarydatafileLabel = uilabel(app.ClusterDefinitionTab);
            app.exemplarydatafileLabel.Text = 'exemplary data file';
            app.exemplarydatafileEditField = uieditfield(app.ClusterDefinitionTab, 'text');
            app.exemplarydatafileEditField.Value = 'H:\data_ck\Vesti\data\H_41W_HD_ID0072\filter_rs6\ffswuaf0010318407-0007-00250-000250-01.nii';
            app.exemplarydatadirButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.exemplarydatadirButton.ButtonPushedFcn = createCallbackFcn(app, @exemplarydatadirButtonPushed, true);
            app.exemplarydatadirButton.Text = '...';
            app.DefineClusterButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.DefineClusterButton.ButtonPushedFcn = createCallbackFcn(app, @DefineClusterButtonPushed, true);
            app.DefineClusterButton.Text = 'Define Cluster';
            app.DefineNetworkButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.DefineNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @DefineNetworkButtonPushed, true);
            app.DefineNetworkButton.Text = 'Define Network';
            app.deletenetworkdefinitionsfromClusterstructButton = uibutton(app.ClusterDefinitionTab, 'push');
            app.deletenetworkdefinitionsfromClusterstructButton.ButtonPushedFcn = createCallbackFcn(app, @deletenetworkdefinitionsfromClusterstructButtonPushed, true);
            app.deletenetworkdefinitionsfromClusterstructButton.Text = 'delete network definitions from Cluster struct';
            app.DataimportTab = uitab(app.TabGroup);
            app.DataimportTab.Title = 'Data import';
            app.g1dirdataimportButton = uibutton(app.DataimportTab, 'push');
            app.g1dirdataimportButton.ButtonPushedFcn = createCallbackFcn(app, @g1dirdataimportButtonPushed, true);
            app.g1dirdataimportButton.Text = '...';
            app.g2dirdataimportButton = uibutton(app.DataimportTab, 'push');
            app.g2dirdataimportButton.ButtonPushedFcn = createCallbackFcn(app, @g2dirdataimportButtonPushed, true);
            app.g2dirdataimportButton.Text = '...';
            app.g1dirdataimportListBox = uilistbox(app.DataimportTab);
            app.g1dirdataimportListBox.Multiselect = 'on';
            app.g1dirdataimportListBox.Value = {'Item 1'};
            app.g2dirdataimportListBox = uilistbox(app.DataimportTab);
            app.g2dirdataimportListBox.Multiselect = 'on';
            app.g2dirdataimportListBox.Value = {'Item 1'};
            app.rootdirgroup1EditField_2Label = uilabel(app.DataimportTab);
            app.rootdirgroup1EditField_2Label.Text = 'root dir group 1';
            app.g1dirdataimportEditField = uieditfield(app.DataimportTab, 'text');
            app.g1dirdataimportEditField.Value = 'H:\data_ck\Vesti\data';
            app.rootdirgroup2EditField_2Label = uilabel(app.DataimportTab);
            app.rootdirgroup2EditField_2Label.Text = 'root dir group 2';
            app.g2dirdataimportEditField = uieditfield(app.DataimportTab, 'text');
            app.g2dirdataimportEditField.Value = 'H:\data_ck\Vesti\data';
            app.subdirnameEditField_2Label = uilabel(app.DataimportTab);
            app.subdirnameEditField_2Label.Text = 'subdir name';
            app.dataimportsubdirnameEditField = uieditfield(app.DataimportTab, 'text');
            app.dataimportsubdirnameEditField.Value = 'filter_rs6';
            app.OptionsforvoxelextractionLabel = uilabel(app.DataimportTab);
            app.OptionsforvoxelextractionLabel.Text = 'Options for voxel extraction';
            app.Panel = uipanel(app.DataimportTab);
            app.Panel.Title = 'unconditional cluster extraction (all voxel given by the Clusterdefinition)';
            app.extractallvoxelinClusterCheckBox = uicheckbox(app.Panel);
            app.extractallvoxelinClusterCheckBox.Text = 'extract all voxel in Cluster';
            app.extractallvoxelinClusterCheckBox.Value = true;
            app.savedasClussteriYLabel = uilabel(app.Panel);
            app.savedasClussteriYLabel.Text = 'saved as Clusster{i}.Y';
            app.Panel_2 = uipanel(app.DataimportTab);
            app.Panel_2.Title = 'conditional cluster extraction (only voxel were extractat which meet the following individ. criteria)';
            app.extractconditionalvoxelCheckBox = uicheckbox(app.Panel_2);
            app.extractconditionalvoxelCheckBox.Text = 'extract conditional voxel';
            app.extractconditionalvoxelCheckBox.Value = true;
            app.savedasClussteriYc1Label = uilabel(app.Panel_2);
            app.savedasClussteriYc1Label.Text = 'saved as Clusster{i}.Yc1';
            app.graymatterprobgreaterasEditFieldLabel = uilabel(app.Panel_2);
            app.graymatterprobgreaterasEditFieldLabel.Text = 'gray matter prob. greater as';
            app.graymatterprobgreaterasEditField = uieditfield(app.Panel_2, 'numeric');
            app.graymatterprobgreaterasEditField.Value = 0.1;
            app.whitematterproblessthenEditFieldLabel = uilabel(app.Panel_2);
            app.whitematterproblessthenEditFieldLabel.Text = 'white matter prob. less then';
            app.whitematterproblessthenEditField = uieditfield(app.Panel_2, 'numeric');
            app.whitematterproblessthenEditField.Value = 0.9;
            app.CSFproblessthenEditFieldLabel = uilabel(app.Panel_2);
            app.CSFproblessthenEditFieldLabel.Text = 'CSF prob. less then';
            app.CSFproblessthenEditField = uieditfield(app.Panel_2, 'numeric');
            app.CSFproblessthenEditField.Value = 0.9;
            app.graymatterfilenameEditFieldLabel = uilabel(app.Panel_2);
            app.graymatterfilenameEditFieldLabel.Text = 'gray matter file name';
            app.graymatterfilenameEditField = uieditfield(app.Panel_2, 'text');
            app.graymatterfilenameEditField.Value = 'rc1_file.nii';
            app.whitematterfilenameEditFieldLabel = uilabel(app.Panel_2);
            app.whitematterfilenameEditFieldLabel.Text = 'white matter file name';
            app.whitematterfilenameEditField = uieditfield(app.Panel_2, 'text');
            app.whitematterfilenameEditField.Value = 'rc2_file.nii';
            app.csffilenameEditFieldLabel = uilabel(app.Panel_2);
            app.csffilenameEditFieldLabel.Text = 'csf file name';
            app.csffilenameEditField = uieditfield(app.Panel_2, 'text');
            app.csffilenameEditField.Value = 'rc3_file.nii';
            app.Lamp = uilamp(app.Panel_2);
            app.testforfilepresenceButton = uibutton(app.Panel_2, 'push');
            app.testforfilepresenceButton.ButtonPushedFcn = createCallbackFcn(app, @testforfilepresenceButtonPushed, true);
            app.testforfilepresenceButton.Text = 'test for file presence';
            app.extract2conditionalvoxelCheckBox = uicheckbox(app.Panel_2);
            app.extract2conditionalvoxelCheckBox.Text = 'extract 2. conditional voxel';
            app.extract2conditionalvoxelCheckBox.Value = true;
            app.graymatterprobgreaterasEditField_2Label = uilabel(app.Panel_2);
            app.graymatterprobgreaterasEditField_2Label.Text = 'gray matter prob. greater as';
            app.graymatterprobgreateras2EditField = uieditfield(app.Panel_2, 'numeric');
            app.graymatterprobgreateras2EditField.Value = 0.4;
            app.whitematterproblessthenEditField_2Label = uilabel(app.Panel_2);
            app.whitematterproblessthenEditField_2Label.Text = 'white matter prob. less then';
            app.whitematterproblessthen2EditField = uieditfield(app.Panel_2, 'numeric');
            app.whitematterproblessthen2EditField.Value = 0.2;
            app.CSFproblessthenEditField_2Label = uilabel(app.Panel_2);
            app.CSFproblessthenEditField_2Label.Text = 'CSF prob. less then';
            app.CSFproblessthen2EditField = uieditfield(app.Panel_2, 'numeric');
            app.CSFproblessthen2EditField.Value = 0.2;
            app.savedasClussteriYc2Label = uilabel(app.Panel_2);
            app.savedasClussteriYc2Label.Text = 'saved as Clusster{i}.Yc2';
            app.GOButton = uibutton(app.DataimportTab, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO!';
            app.updatedataimportButton = uibutton(app.DataimportTab, 'push');
            app.updatedataimportButton.ButtonPushedFcn = createCallbackFcn(app, @updatedataimportButtonPushed, true);
            app.updatedataimportButton.Text = 'update';
            app.filefilterLabel = uilabel(app.DataimportTab);
            app.filefilterLabel.Text = 'filefilter';
            app.dataimportfilefilterEditField = uieditfield(app.DataimportTab, 'text');
            app.dataimportfilefilterEditField.Value = 'ff*.nii';
            app.DatapropertiesPanel = uipanel(app.DataimportTab);
            app.DatapropertiesPanel.Title = 'Data properties';
            app.TRinsecEditFieldLabel = uilabel(app.DatapropertiesPanel);
            app.TRinsecEditFieldLabel.Text = 'TR in sec';
            app.TREditField = uieditfield(app.DatapropertiesPanel, 'numeric');
            app.TREditField.Value = 1.9;
            app.lpfEditFieldLabel = uilabel(app.DatapropertiesPanel);
            app.lpfEditFieldLabel.Text = 'lpf';
            app.lpfEditField = uieditfield(app.DatapropertiesPanel, 'numeric');
            app.lpfEditField.Value = 0.1;
            app.hpfEditFieldLabel = uilabel(app.DatapropertiesPanel);
            app.hpfEditFieldLabel.Text = 'hpf';
            app.hpfEditField = uieditfield(app.DatapropertiesPanel, 'numeric');
            app.hpfEditField.Value = 0.01;
            app.connectivityestimationTab = uitab(app.TabGroup);
            app.connectivityestimationTab.Title = 'connectivity estimation';
            app.estimateconnectivityButton = uibutton(app.connectivityestimationTab, 'push');
            app.estimateconnectivityButton.ButtonPushedFcn = createCallbackFcn(app, @estimateconnectivityButtonPushed, true);
            app.estimateconnectivityButton.Text = 'estimate connectivity';
            app.estimationoptionsPanel = uipanel(app.connectivityestimationTab);
            app.estimationoptionsPanel.Title = 'estimation options';
            app.Cluster2VoxelCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.Cluster2VoxelCheckBox.Text = 'Cluster2Voxel';
            app.Cluster2ClusterCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.Cluster2ClusterCheckBox.Text = 'Cluster2Cluster';
            app.Cluster2ClusterCheckBox.Value = true;
            app.Voxel2VoxelCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.Voxel2VoxelCheckBox.Text = 'Voxel2Voxel';
            app.C1maskthresholdCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.C1maskthresholdCheckBox.Text = 'C1 mask threshold';
            app.thresholdEditFieldLabel = uilabel(app.estimationoptionsPanel);
            app.thresholdEditFieldLabel.Text = 'threshold';
            app.thresholdEditField = uieditfield(app.estimationoptionsPanel, 'numeric');
            app.thresholdEditField.Value = 0.1;
            app.smoothingCheckBox = uicheckbox(app.estimationoptionsPanel);
            app.smoothingCheckBox.Text = 'smoothing';
            app.kernelEditFieldLabel = uilabel(app.estimationoptionsPanel);
            app.kernelEditFieldLabel.Text = 'kernel';
            app.kernelEditField = uieditfield(app.estimationoptionsPanel, 'text');
            app.kernelEditField.Value = '[6 6 6]';
            app.MethodsPanel = uipanel(app.connectivityestimationTab);
            app.MethodsPanel.Title = 'Methods';
            app.synchronizationPanel = uipanel(app.MethodsPanel);
            app.synchronizationPanel.Title = 'synchronization';
            app.readmesynchronizationButton = uibutton(app.synchronizationPanel, 'push');
            app.readmesynchronizationButton.Text = 'readme';
            app.histogramEditField_2Label = uilabel(app.synchronizationPanel);
            app.histogramEditField_2Label.Text = 'histogram';
            app.histogramsynchronizationEditField = uieditfield(app.synchronizationPanel, 'numeric');
            app.histogramsynchronizationEditField.Value = 4;
            app.complexityPanel = uipanel(app.MethodsPanel);
            app.complexityPanel.Title = 'complexity';
            app.windowlengthEditFieldLabel = uilabel(app.complexityPanel);
            app.windowlengthEditFieldLabel.Text = 'window length';
            app.windowlengthcomplexityEditField = uieditfield(app.complexityPanel, 'numeric');
            app.windowlengthcomplexityEditField.Value = 5;
            app.similaritymeasureEditFieldLabel = uilabel(app.complexityPanel);
            app.similaritymeasureEditFieldLabel.Text = 'similarity measure';
            app.similaritymeasurecomplexityEditField = uieditfield(app.complexityPanel, 'numeric');
            app.similaritymeasurecomplexityEditField.Value = 0.15;
            app.readmecomplexityButton = uibutton(app.complexityPanel, 'push');
            app.readmecomplexityButton.Text = 'readme';
            app.transinformationPanel = uipanel(app.MethodsPanel);
            app.transinformationPanel.Title = 'transinformation';
            app.readmetransinformationButton = uibutton(app.transinformationPanel, 'push');
            app.readmetransinformationButton.Text = 'readme';
            app.histogramEditFieldLabel = uilabel(app.transinformationPanel);
            app.histogramEditFieldLabel.Text = 'histogram';
            app.histogramEditField = uieditfield(app.transinformationPanel, 'numeric');
            app.histogramEditField.Value = 4;
            app.timelagEditFieldLabel = uilabel(app.transinformationPanel);
            app.timelagEditFieldLabel.Text = 'time lag';
            app.timelagEditField = uieditfield(app.transinformationPanel, 'numeric');
            app.timelagEditField.Value = 5;
            app.complexitySwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.complexitySwitch.Items = {'0', '1'};
            app.complexitySwitch.ValueChangedFcn = createCallbackFcn(app, @complexitySwitchValueChanged, true);
            app.complexitySwitch.Value = '0';
            app.correlationsPanel = uipanel(app.MethodsPanel);
            app.correlationsPanel.Title = 'correlations';
            app.coherencePanel = uipanel(app.MethodsPanel);
            app.coherencePanel.Title = 'coherence';
            app.readmecoherenceButton = uibutton(app.coherencePanel, 'push');
            app.readmecoherenceButton.Text = 'readme';
            app.partialcoherencePanel = uipanel(app.MethodsPanel);
            app.partialcoherencePanel.Title = 'partial coherence';
            app.readmepartialcoherenceButton = uibutton(app.partialcoherencePanel, 'push');
            app.readmepartialcoherenceButton.Text = 'readme';
            app.synchronizationSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.synchronizationSwitch.Items = {'0', '1'};
            app.synchronizationSwitch.ValueChangedFcn = createCallbackFcn(app, @synchronizationSwitchValueChanged, true);
            app.synchronizationSwitch.Value = '0';
            app.transinformationSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.transinformationSwitch.Items = {'0', '1'};
            app.transinformationSwitch.ValueChangedFcn = createCallbackFcn(app, @transinformationSwitchValueChanged, true);
            app.transinformationSwitch.Value = '0';
            app.partialcoherenceSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.partialcoherenceSwitch.Items = {'0', '1'};
            app.partialcoherenceSwitch.ValueChangedFcn = createCallbackFcn(app, @partialcoherenceSwitchValueChanged, true);
            app.partialcoherenceSwitch.Enable = 'off';
            app.partialcoherenceSwitch.Value = '0';
            app.coherenceSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.coherenceSwitch.Items = {'0', '1'};
            app.coherenceSwitch.ValueChangedFcn = createCallbackFcn(app, @coherenceSwitchValueChanged, true);
            app.coherenceSwitch.Value = '0';
            app.correlationSwitch = uiswitch(app.MethodsPanel, 'toggle');
            app.correlationSwitch.Items = {'0', '1'};
            app.correlationSwitch.ValueChangedFcn = createCallbackFcn(app, @correlationSwitchValueChanged, true);
            app.correlationSwitch.Value = '0';
            app.grangerSwitch = uiswitch(app.connectivityestimationTab, 'toggle');
            app.grangerSwitch.Items = {'0', '1'};
            app.grangerSwitch.ValueChangedFcn = createCallbackFcn(app, @grangerSwitchValueChanged, true);
            app.grangerSwitch.Enable = 'off';
            app.grangerSwitch.Value = '0';
            app.GrangercausalitybigPanel = uipanel(app.connectivityestimationTab);
            app.GrangercausalitybigPanel.Title = 'Granger causality';
            app.GrangercausalityPanel = uipanel(app.GrangercausalitybigPanel);
            app.GrangercausalityPanel.Title = 'Granger causality';
            app.conditionalGCinthetimedomainCheckBox = uicheckbox(app.GrangercausalityPanel);
            app.conditionalGCinthetimedomainCheckBox.Text = 'conditional GC in the time domain';
            app.additionalpreprocessingPanel = uipanel(app.GrangercausalityPanel);
            app.additionalpreprocessingPanel.Title = 'additional preprocessing';
            app.grangerdetrenddataCheckBox = uicheckbox(app.additionalpreprocessingPanel);
            app.grangerdetrenddataCheckBox.Text = 'detrend data';
            app.grangerremovetemporalmeanCheckBox = uicheckbox(app.additionalpreprocessingPanel);
            app.grangerremovetemporalmeanCheckBox.Text = 'remove temporal mean';
            app.grangerremovetemporalmeananddividebystdCheckBox = uicheckbox(app.additionalpreprocessingPanel);
            app.grangerremovetemporalmeananddividebystdCheckBox.Text = 'remove temporal mean and divide by std';
            app.grangerdifferencingtimeseriesCheckBox = uicheckbox(app.additionalpreprocessingPanel);
            app.grangerdifferencingtimeseriesCheckBox.Text = 'differencing time series';
            app.iterationEditFieldLabel = uilabel(app.additionalpreprocessingPanel);
            app.iterationEditFieldLabel.Text = 'iteration';
            app.grangeriterationEditField = uieditfield(app.additionalpreprocessingPanel, 'numeric');
            app.grangeriterationEditField.Value = 1;
            app.readmegrangerpreprocessingButton = uibutton(app.additionalpreprocessingPanel, 'push');
            app.readmegrangerpreprocessingButton.Text = 'readme';
            app.estimategrangerautonomyCheckBox = uicheckbox(app.GrangercausalityPanel);
            app.estimategrangerautonomyCheckBox.Text = 'estimate granger autonomy';
            app.conditionalGCinfrequencydomainCheckBox = uicheckbox(app.GrangercausalityPanel);
            app.conditionalGCinfrequencydomainCheckBox.Text = 'conditional GC in frequency domain';
            app.readmegrangerpreprocessingButton_2 = uibutton(app.GrangercausalityPanel, 'push');
            app.readmegrangerpreprocessingButton_2.Text = 'readme';
            app.CGCtimeparameterPanel = uipanel(app.GrangercausalityPanel);
            app.CGCtimeparameterPanel.Title = 'CGC time parameter';
            app.numberoflagsEditFieldLabel = uilabel(app.CGCtimeparameterPanel);
            app.numberoflagsEditFieldLabel.Text = 'number of lags';
            app.grangernumberoflagsEditField = uieditfield(app.CGCtimeparameterPanel, 'numeric');
            app.grangernumberoflagsEditField.Value = 1;
            app.pEditFieldLabel = uilabel(app.CGCtimeparameterPanel);
            app.pEditFieldLabel.Text = 'p =';
            app.grangerpEditField = uieditfield(app.CGCtimeparameterPanel, 'numeric');
            app.grangerpEditField.Value = 0.05;
            app.frequencyparameterPanel = uipanel(app.GrangercausalityPanel);
            app.frequencyparameterPanel.Title = 'frequency parameter';
            app.frequencyiterationstepEditFieldLabel = uilabel(app.frequencyparameterPanel);
            app.frequencyiterationstepEditFieldLabel.Text = 'frequency iteration step';
            app.grangerfrequencyiterationstepEditField = uieditfield(app.frequencyparameterPanel, 'numeric');
            app.grangerfrequencyiterationstepEditField.Value = 0.01;
            app.frequencyEditFieldLabel = uilabel(app.frequencyparameterPanel);
            app.frequencyEditFieldLabel.Text = 'frequency';
            app.grangerfrequencyEditField = uieditfield(app.frequencyparameterPanel, 'text');
            app.grangerfrequencyEditField.Value = '[0.01 0.1]';
            app.selectedClusterListBoxLabel = uilabel(app.GrangercausalitybigPanel);
            app.selectedClusterListBoxLabel.Text = 'selected Cluster';
            app.grangerselectedClusterListBox = uilistbox(app.GrangercausalitybigPanel);
            app.usedataPanel = uipanel(app.connectivityestimationTab);
            app.usedataPanel.Title = 'use data';
            app.conuseYCheckBox = uicheckbox(app.usedataPanel);
            app.conuseYCheckBox.Text = 'Y';
            app.conuseYCheckBox.Value = true;
            app.conuseYc1CheckBox = uicheckbox(app.usedataPanel);
            app.conuseYc1CheckBox.Text = 'Yc1';
            app.conuseYc1CheckBox.Value = true;
            app.conuseYc2CheckBox = uicheckbox(app.usedataPanel);
            app.conuseYc2CheckBox.Text = 'Yc2';
            app.conuseYc2CheckBox.Value = true;
            app.datareductionTab = uitab(app.TabGroup);
            app.datareductionTab.Title = 'data reduction';
            app.deleteifpossiblePanel = uipanel(app.datareductionTab);
            app.deleteifpossiblePanel.Title = 'delete if possible';
            app.YCheckBox = uicheckbox(app.deleteifpossiblePanel);
            app.YCheckBox.Text = 'Y';
            app.Yc1CheckBox = uicheckbox(app.deleteifpossiblePanel);
            app.Yc1CheckBox.Text = 'Yc1';
            app.Yc2CheckBox = uicheckbox(app.deleteifpossiblePanel);
            app.Yc2CheckBox.Text = 'Yc2';
            app.newprefixforClustermatfilesEditFieldLabel = uilabel(app.deleteifpossiblePanel);
            app.newprefixforClustermatfilesEditFieldLabel.Text = 'new prefix for Cluster.mat files';
            app.newprefixforClustermatfilesEditField = uieditfield(app.deleteifpossiblePanel, 'text');
            app.newprefixforClustermatfilesEditField.Value = 'red_';
            app.reduceClusterdataButton = uibutton(app.datareductionTab, 'push');
            app.reduceClusterdataButton.ButtonPushedFcn = createCallbackFcn(app, @reduceClusterdataButtonPushed, true);
            app.reduceClusterdataButton.Text = 'reduce Cluster data';
            app.ExplanationTextAreaLabel = uilabel(app.datareductionTab);
            app.ExplanationTextAreaLabel.Text = 'Explanation';
            app.ExplanationTextArea = uitextarea(app.datareductionTab);
            app.ExplanationTextArea.Value = {'Much of the estimation time is consumed by loading the Cluster.mat files. The major amount of memory size is consumed by the original data from the included voxel. However, only the mean values for every Cluster will be used further. Therefore, if Clustersize is large it might be reasonable to delete the original data and save only the Y_mean values. This is performed by the current function - reduce Cluster structure.'; ''};
            app.visualizationTab = uitab(app.TabGroup);
            app.visualizationTab.Title = 'visualization';
            app.TabGroup2 = uitabgroup(app.visualizationTab);
            app.Cluster2Cluster = uitab(app.TabGroup2);
            app.Cluster2Cluster.Title = 'Cluster2Cluster';
            app.loadeddataPanel = uipanel(app.Cluster2Cluster);
            app.loadeddataPanel.Title = 'loaded data';
            app.C2CloaddataButton = uibutton(app.loadeddataPanel, 'push');
            app.C2CloaddataButton.ButtonPushedFcn = createCallbackFcn(app, @C2CloaddataButtonPushed, true);
            app.C2CloaddataButton.Text = 'load data';
            app.C2CloaddataLamp = uilamp(app.loadeddataPanel);
            app.group1ListBoxLabel = uilabel(app.loadeddataPanel);
            app.group1ListBoxLabel.Text = 'group 1';
            app.C2Cgroup1ListBox = uilistbox(app.loadeddataPanel);
            app.C2Cgroup1ListBox.Multiselect = 'on';
            app.C2Cgroup1ListBox.Value = {'Item 1'};
            app.group2ListBoxLabel = uilabel(app.loadeddataPanel);
            app.group2ListBoxLabel.Text = 'group 2';
            app.C2Cgroup2ListBox = uilistbox(app.loadeddataPanel);
            app.C2Cgroup2ListBox.Multiselect = 'on';
            app.C2Cgroup2ListBox.Value = {'Item 1'};
            app.C2CnormalizeCheckBox = uicheckbox(app.loadeddataPanel);
            app.C2CnormalizeCheckBox.Text = 'normalize';
            app.C2CestimateClusterstatisticButton = uibutton(app.loadeddataPanel, 'push');
            app.C2CestimateClusterstatisticButton.ButtonPushedFcn = createCallbackFcn(app, @C2CestimateClusterstatisticButtonPushed, true);
            app.C2CestimateClusterstatisticButton.Text = 'estimate Cluster statistic';
            app.C2CstatisticButtonGroup = uibuttongroup(app.loadeddataPanel);
            app.C2CstatisticButtonGroup.Title = 'statistic';
            app.C2CtwosampleButton = uitogglebutton(app.C2CstatisticButtonGroup);
            app.C2CtwosampleButton.Text = 'two-sample';
            app.C2CtwosampleButton.Value = true;
            app.C2CpairedButton = uitogglebutton(app.C2CstatisticButtonGroup);
            app.C2CpairedButton.Text = 'paired';
            app.C2CestimatedataLamp = uilamp(app.loadeddataPanel);
            app.C2CDatatouseButtonGroup = uibuttongroup(app.loadeddataPanel);
            app.C2CDatatouseButtonGroup.Title = 'Data to use';
            app.C2CYButton = uiradiobutton(app.C2CDatatouseButtonGroup);
            app.C2CYButton.Text = 'Y';
            app.C2CYButton.Value = true;
            app.C2CYc1Button = uiradiobutton(app.C2CDatatouseButtonGroup);
            app.C2CYc1Button.Text = 'Yc1';
            app.C2CYc2Button = uiradiobutton(app.C2CDatatouseButtonGroup);
            app.C2CYc2Button.Text = 'Yc2';
            app.C2CestimateClusterstatisticButton_2 = uibutton(app.loadeddataPanel, 'push');
            app.C2CestimateClusterstatisticButton_2.ButtonPushedFcn = createCallbackFcn(app, @C2CestimateClusterstatisticButton_2Pushed, true);
            app.C2CestimateClusterstatisticButton_2.Text = 'estimate Cluster statistic';
            app.C2CloaddataButton_2 = uibutton(app.loadeddataPanel, 'push');
            app.C2CloaddataButton_2.ButtonPushedFcn = createCallbackFcn(app, @C2CloaddataButton_2Pushed, true);
            app.C2CloaddataButton_2.Text = 'load data';
            app.C2CshownetworksButton = uibutton(app.Cluster2Cluster, 'push');
            app.C2CshownetworksButton.ButtonPushedFcn = createCallbackFcn(app, @C2CshownetworksButtonPushed, true);
            app.C2CshownetworksButton.Text = 'show networks';
            app.BrainconnectivityPanel = uipanel(app.Cluster2Cluster);
            app.BrainconnectivityPanel.Title = 'Brain connectivity';
            app.C2CmeanZdiffButton = uibutton(app.BrainconnectivityPanel, 'push');
            app.C2CmeanZdiffButton.ButtonPushedFcn = createCallbackFcn(app, @C2CmeanZdiffButtonPushed, true);
            app.C2CmeanZdiffButton.Text = 'mean Z diff';
            app.C2CBCsigdiffButton = uibutton(app.BrainconnectivityPanel, 'push');
            app.C2CBCsigdiffButton.ButtonPushedFcn = createCallbackFcn(app, @C2CBCsigdiffButtonPushed, true);
            app.C2CBCsigdiffButton.Text = 'sig diff';
            app.BehaviorvsBrainconnectivitywithEXELdataPanel = uipanel(app.Cluster2Cluster);
            app.BehaviorvsBrainconnectivitywithEXELdataPanel.Title = 'Behavior vs. Brain connectivity with EXEL data';
            app.xlscsvFileEditFieldLabel = uilabel(app.BehaviorvsBrainconnectivitywithEXELdataPanel);
            app.xlscsvFileEditFieldLabel.Text = 'xls/csv File';
            app.xlscsvFileEditField = uieditfield(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'text');
            app.xlscsvFileEditField.Value = 'G:\Google Drive\paper\02_Vesti_bilateral\Vertigo_Behavioral20180321.xlsx';
            app.C2CchooseexelfileButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CchooseexelfileButton.ButtonPushedFcn = createCallbackFcn(app, @C2CchooseexelfileButtonPushed, true);
            app.C2CchooseexelfileButton.Text = '...';
            app.C2CexelreadmeButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CexelreadmeButton.ButtonPushedFcn = createCallbackFcn(app, @C2CexelreadmeButtonPushed, true);
            app.C2CexelreadmeButton.Text = 'readme';
            app.loadexelfileButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.loadexelfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadexelfileButtonPushed, true);
            app.loadexelfileButton.Text = 'load exel file';
            app.ExelvariablesListBoxLabel = uilabel(app.BehaviorvsBrainconnectivitywithEXELdataPanel);
            app.ExelvariablesListBoxLabel.Text = 'Exel variables';
            app.ExelvariablesListBox = uilistbox(app.BehaviorvsBrainconnectivitywithEXELdataPanel);
            app.ExelvariablesListBox.Multiselect = 'on';
            app.ExelvariablesListBox.Value = {'Item 1'};
            app.C2CestimatecorrelationButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CestimatecorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @C2CestimatecorrelationButtonPushed, true);
            app.C2CestimatecorrelationButton.Text = 'estimate correlation';
            app.visualizecorrelationButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.visualizecorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @visualizecorrelationButtonPushed, true);
            app.visualizecorrelationButton.Text = 'visualize correlation';
            app.C2CestimateexelcorrelationLamp = uilamp(app.BehaviorvsBrainconnectivitywithEXELdataPanel);
            app.C2CvisualizenetworkcorrelationButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CvisualizenetworkcorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @C2CvisualizenetworkcorrelationButtonPushed, true);
            app.C2CvisualizenetworkcorrelationButton.Text = 'visualize network correlation';
            app.C2CestimatenetworkcorrelationButton = uibutton(app.BehaviorvsBrainconnectivitywithEXELdataPanel, 'push');
            app.C2CestimatenetworkcorrelationButton.ButtonPushedFcn = createCallbackFcn(app, @C2CestimatenetworkcorrelationButtonPushed, true);
            app.C2CestimatenetworkcorrelationButton.Text = 'estimate network correlation';
            app.Cluster2Voxel = uitab(app.TabGroup2);
            app.Cluster2Voxel.Title = 'Cluster2Voxel';
            app.Voxel2Voxel = uitab(app.TabGroup2);
            app.Voxel2Voxel.Title = 'Voxel2Voxel';
            app.Cluster2Cluster_Granger = uitab(app.TabGroup2);
            app.Cluster2Cluster_Granger.Title = 'Cluster2Cluster_Granger';
            app.GlobalParameterPanel = uipanel(app.UIFigure);
            app.GlobalParameterPanel.Title = 'Global Parameter';
            app.resultsrootdirEditFieldLabel = uilabel(app.GlobalParameterPanel);
            app.resultsrootdirEditFieldLabel.Text = 'results root dir';
            app.resultsdirEditField = uieditfield(app.GlobalParameterPanel, 'text');
            app.resultsdirEditField.Value = 'H:\data_ck\Vesti\analyse\out3';
            app.Buttonchooseresultsdir = uibutton(app.GlobalParameterPanel, 'push');
            app.Buttonchooseresultsdir.ButtonPushedFcn = createCallbackFcn(app, @ButtonchooseresultsdirPushed, true);
            app.Buttonchooseresultsdir.Text = '...';
            app.Buttonchooseg1dir = uibutton(app.GlobalParameterPanel, 'push');
            app.Buttonchooseg1dir.ButtonPushedFcn = createCallbackFcn(app, @Buttonchooseg1dirPushed, true);
            app.Buttonchooseg1dir.Text = '...';
            app.group1dirEditFieldLabel = uilabel(app.GlobalParameterPanel);
            app.group1dirEditFieldLabel.Text = 'group 1 dir';
            app.g1dirEditField = uieditfield(app.GlobalParameterPanel, 'text');
            app.g1dirEditField.Value = 'H:\data_ck\Vesti\analyse\out3\VB';
            app.chooseg2dir = uibutton(app.GlobalParameterPanel, 'push');
            app.chooseg2dir.ButtonPushedFcn = createCallbackFcn(app, @chooseg2dirButtonPushed, true);
            app.chooseg2dir.Text = '...';
            app.group2dirEditFieldLabel = uilabel(app.GlobalParameterPanel);
            app.group2dirEditFieldLabel.Text = 'group 2 dir';
            app.g2dirEditField = uieditfield(app.GlobalParameterPanel, 'text');
            app.g2dirEditField.Value = 'H:\data_ck\Vesti\analyse\out3\H';
            app.loadlastButton = uibutton(app.GlobalParameterPanel, 'push');
            app.loadlastButton.ButtonPushedFcn = createCallbackFcn(app, @loadlastButtonPushed, true);
            app.loadlastButton.Text = 'load last';
            app.loadButton = uibutton(app.GlobalParameterPanel, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.saveButton = uibutton(app.GlobalParameterPanel, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.MultiprocessoroptionsCheckBox = uicheckbox(app.GlobalParameterPanel);
            app.MultiprocessoroptionsCheckBox.Text = 'Multiprocessor options';
            app.initnewButton = uibutton(app.GlobalParameterPanel, 'push');
            app.initnewButton.ButtonPushedFcn = createCallbackFcn(app, @initnewButtonPushed, true);
            app.initnewButton.Text = 'init/new';
            app.idleGaugeLabel = uilabel(app.GlobalParameterPanel);
            app.idleGaugeLabel.Text = 'idle';
            app.idleGauge = uigauge(app.GlobalParameterPanel, 'linear');
            app.idleGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.idleGauge.MinorTicks = [];
            app.loadnewsbButton = uibutton(app.GlobalParameterPanel, 'push');
            app.loadnewsbButton.ButtonPushedFcn = createCallbackFcn(app, @loadnewsbButtonPushed, true);
            app.loadnewsbButton.Text = 'load (new sb)';
            app.savenewsbButton = uibutton(app.GlobalParameterPanel, 'push');
            app.savenewsbButton.ButtonPushedFcn = createCallbackFcn(app, @savenewsbButtonPushed, true);
            app.savenewsbButton.Text = 'save (new sb)';
            app.g1ListBox = uilistbox(app.UIFigure);
            app.g1ListBox.Multiselect = 'on';
            app.g1ListBox.Value = {'Item 1'};
            app.g2ListBox = uilistbox(app.UIFigure);
            app.g2ListBox.Multiselect = 'on';
            app.g2ListBox.Value = {'Item 1'};
            app.group1directoryLabel = uilabel(app.UIFigure);
            app.group1directoryLabel.Text = 'group 1 directory';
            app.group2directoryLabel = uilabel(app.UIFigure);
            app.group2directoryLabel.Text = 'group 2 directory';
            app.updateButton = uibutton(app.UIFigure, 'push');
            app.updateButton.ButtonPushedFcn = createCallbackFcn(app, @updateButtonPushed, true);
            app.updateButton.Text = 'update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TEMPLATE_ck_rc3_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end